classdef annotationTypeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnnotationTypeSelectorUIFigure  matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        titleText                       matlab.ui.control.Label
        selTypeTree                     matlab.ui.container.CheckBoxTree
        typesNode                       matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        annotTypes = {};
        callerApp = [];
        selAnnotTypes = {};
    end
    
    methods (Access = private)
        
        function populateTypesList(app)
            a = app.typesNode.Children;
            a.delete;
            for ati = 1:length(app.annotTypes)
                annotType = app.annotTypes{ati};
                % Create Node
                newNode = uitreenode(app.typesNode);
                newNode.Text = annotType;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, typesList)
            app.annotTypes = typesList;
            app.callerApp = callerApp;
            app.populateTypesList();
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            checkedNodes = app.selTypeTree.CheckedNodes;
            if not(isempty(checkedNodes))
                for cni = 1:size(checkedNodes,1)
                    app.selAnnotTypes = ...
                        cat(1, app.selAnnotTypes, checkedNodes(cni).Text);
                end
                app.selAnnotTypes = unique(app.selAnnotTypes,'stable');
            end
            app.callerApp.updateValidAnnotTypes(app.selAnnotTypes);
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnnotationTypeSelectorUIFigure = uifigure('Visible', 'off');
            app.AnnotationTypeSelectorUIFigure.Name = 'Annotation Type Selector';
            app.AnnotationTypeSelectorUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.selTypeTree = uitree(app.AnnotationTypeSelectorUIFigure, 'checkbox');
            app.selTypeTree.Editable = 'on';
            app.typesNode = uitreenode(app.selTypeTree);
            app.typesNode.Text = 'All';
            % Assign Checked Nodes
            app.selTypeTree.CheckedNodes = [app.typesNode];
            app.titleText = uilabel(app.AnnotationTypeSelectorUIFigure);
            app.titleText.Text = 'Select annotation type to show';
            app.OKButton = uibutton(app.AnnotationTypeSelectorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.AnnotationTypeSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotationTypeSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnnotationTypeSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnnotationTypeSelectorUIFigure)
        end
    end
end