classdef annotationsSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotsSet                  matlab.ui.Figure
        savedEventColorButton      matlab.ui.control.Button
        titleText                  matlab.ui.control.Label
        eventNameLabel             matlab.ui.control.Label
        newEventName               matlab.ui.control.TextArea
        colorLabel                 matlab.ui.control.Label
        eventExistsLabel           matlab.ui.control.Label
        buttonNewColor             matlab.ui.control.Button
        addNewEvent                matlab.ui.control.Button
        definedEventsListBox       matlab.ui.control.ListBox
        savedEventColorLabel       matlab.ui.control.Label
        DefinedEventsListBoxLabel  matlab.ui.control.Label
        deleteButton               matlab.ui.control.Button
        saveButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        newSelectedColor = [];%[0    0.4471    0.7412];
        eventsAndColorList = {}; % Description
        callingAppHdl = [];
    end
    
    methods (Access = private)
        
        function initVariables(app)
            app.newSelectedColor = [];
            app.definedEventsListBox.Items = app.eventsAndColorList(:,1);
            app.savedEventColorButton.BackgroundColor = [1 1 1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, existingTypesAndColorsList)
            app.callingAppHdl = callingApp;
            app.eventsAndColorList = existingTypesAndColorsList;
            app.initVariables();
        end

        % Button pushed function: buttonNewColor
        function buttonNewColorPushed(app, event)
            c = uisetcolor();
            if length(c) == 3
                app.newSelectedColor = c;
                app.buttonNewColor.BackgroundColor = app.newSelectedColor;
            end
        end

        % Button pushed function: addNewEvent
        function addNewEventButtonPushed(app, event)
            if isempty(app.newEventName.Value{1}) || sum(app.buttonNewColor.BackgroundColor - [1 1 1]) == 0
                %% event type or color not defined
                app.eventExistsLabel.Text = {'Not Enough'; 'Event Info!'};
                app.eventExistsLabel.Visible = 'on'; pause(1);
                app.eventExistsLabel.Visible = 'off';
            else
                %% event type and color are defined
                newEventName = app.newEventName.Value{1};
                newEventColor = app.newSelectedColor;
                newEventEntree = {newEventName, newEventColor};
                eventExists = sum(ismember(app.eventsAndColorList(:,1), newEventName))>0;
                if isempty(eventExists) || not(eventExists)
                    %% event list is empty or new event is not yet in the list (i.e. not repeated)
                    app.eventsAndColorList = cat(1, app.eventsAndColorList, newEventEntree);

                    app.newSelectedColor = [];
                    app.newEventName.Value = '';
                    app.buttonNewColor.BackgroundColor = [1 1 1];

                    app.definedEventsListBox.Items = app.eventsAndColorList(:,1);

                else
                    %% new event is already in the list
                    app.eventExistsLabel.Text = {'Event '; 'already exists!'};
                    app.eventExistsLabel.Visible = 'on'; pause(1);
                    app.eventExistsLabel.Visible = 'off';
                end    

            end
        end

        % Clicked callback: definedEventsListBox
        function definedEventsListBoxClicked(app, event)
            itemIdx = event.InteractionInformation.Item;
            app.savedEventColorButton.BackgroundColor = app.eventsAndColorList{itemIdx, 2};
        end

        % Value changed function: definedEventsListBox
        function definedEventsListBoxValueChanged(app, event)
            value = app.definedEventsListBox.Value;
            itemIdx = find(ismember(app.eventsAndColorList(:,1), value));
            app.savedEventColorButton.BackgroundColor = app.eventsAndColorList{itemIdx, 2}; 
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            value = app.definedEventsListBox.Value;
            itemIdx = find(ismember(app.eventsAndColorList(:,1), value));
            app.eventsAndColorList(itemIdx, :) = [];
            app.definedEventsListBox.Items = app.eventsAndColorList(:,1);
            app.savedEventColorButton.BackgroundColor = [1 1 1];
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.callingAppHdl.saveAnnotationSettings(app.eventsAndColorList);
        end

        % Button pushed function: savedEventColorButton
        function savedEventColorButtonPushed(app, event)
            c = uisetcolor();
            if length(c) == 3
                value = app.definedEventsListBox.Value;
                itemIdx = find(ismember(app.eventsAndColorList(:,1), value));
                app.eventsAndColorList{itemIdx,2} = c;
                app.definedEventsListBox.Items = app.eventsAndColorList(:,1);
                app.savedEventColorButton.BackgroundColor = app.eventsAndColorList{itemIdx, 2};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.annotsSet = uifigure('Visible', 'off');
            app.annotsSet.Name = 'Annotations Setup';
            app.annotsSet.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.saveButton = uibutton(app.annotsSet, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save';
            app.deleteButton = uibutton(app.annotsSet, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'Delete';
            app.DefinedEventsListBoxLabel = uilabel(app.annotsSet);
            app.DefinedEventsListBoxLabel.Text = 'Defined Events';
            app.savedEventColorLabel = uilabel(app.annotsSet);
            app.savedEventColorLabel.Text = {'Saved '; 'Event Color'};
            app.definedEventsListBox = uilistbox(app.annotsSet);
            app.definedEventsListBox.Items = {};
            app.definedEventsListBox.ValueChangedFcn = createCallbackFcn(app, @definedEventsListBoxValueChanged, true);
            app.definedEventsListBox.ClickedFcn = createCallbackFcn(app, @definedEventsListBoxClicked, true);
            app.definedEventsListBox.Value = {};
            app.addNewEvent = uibutton(app.annotsSet, 'push');
            app.addNewEvent.ButtonPushedFcn = createCallbackFcn(app, @addNewEventButtonPushed, true);
            app.addNewEvent.Text = 'Add';
            app.buttonNewColor = uibutton(app.annotsSet, 'push');
            app.buttonNewColor.ButtonPushedFcn = createCallbackFcn(app, @buttonNewColorPushed, true);
            app.buttonNewColor.Text = '';
            app.eventExistsLabel = uilabel(app.annotsSet);
            app.eventExistsLabel.Visible = 'off';
            app.eventExistsLabel.Text = {'Event '; 'already exists!'};
            app.colorLabel = uilabel(app.annotsSet);
            app.colorLabel.Text = {'New '; 'Event Color'};
            app.newEventName = uitextarea(app.annotsSet);
            app.eventNameLabel = uilabel(app.annotsSet);
            app.eventNameLabel.Text = {'New Event '; 'Name'};
            app.titleText = uilabel(app.annotsSet);
            app.titleText.Text = 'Annotations Settings';
            app.savedEventColorButton = uibutton(app.annotsSet, 'push');
            app.savedEventColorButton.ButtonPushedFcn = createCallbackFcn(app, @savedEventColorButtonPushed, true);
            app.savedEventColorButton.Text = '';
            % Show the figure after all components are created
            app.annotsSet.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotationsSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.annotsSet)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotsSet)
        end
    end
end