classdef annotationsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotationsTableUIFigure     matlab.ui.Figure
        GoToSelectedAnnotationLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        annotationsCell = {};
        callingApp = [];
    end
    
    methods (Access = public)
        
        function updateAnnotationsTable(app, annotsList)
            % Fill first four columns, channel, type, startTime, endTime
            app.annotationsCell = annotsList(:,1:4);

            % Fill column 5, channel scope
            selMultChanAnn = cell2mat(annotsList(:,8))==0;
            allChCell = repmat({'Single'}, size(annotsList,1),1);
            allChCell(selMultChanAnn) = {'Multichannel'};
            
            % Fill column 6, creation Time
            creationCell = cellfun(@convertStringsToChars, annotsList(:,9), 'UniformOutput', false);

            % Fill column 7, username
            usernameCell = cellfun(@convertStringsToChars, annotsList(:,10), 'UniformOutput', false);

            % Fill column 8, comments
            commentsCell = cellfun(@convertStringsToChars, annotsList(:,7), 'UniformOutput', false);

            app.annotationsCell = cat(2, app.annotationsCell, allChCell, creationCell, usernameCell, commentsCell);

            app.annotationsCell = sortrows(app.annotationsCell, 3);
            app.UITable.Data = app.annotationsCell;
            app.UITable.Type
            app.UITable.ColumnFormat = {'char', 'char', 'bank', 'bank', 'char', 'char', 'char', 'char'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, annotsList)
            app.annotationsTableUIFigure.Position(1:2) = [1000 200];
            app.callingApp = callingApp;

            app.updateAnnotationsTable(annotsList)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            selAnnotIdx = event.Indices(1,1);
            goToTime = app.annotationsCell{selAnnotIdx, 3};
            app.callingApp.goToAnnotation(goToTime);
        end

        % Close request function: annotationsTableUIFigure
        function annotationsTableUIFigureCloseRequest(app, event)
            app.callingApp.clearAnnotationTableHdl();
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.annotationsTableUIFigure = uifigure('Visible', 'off');
            app.annotationsTableUIFigure.Name = 'annotationsTable';
            app.annotationsTableUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.annotationsTableUIFigure.CloseRequestFcn = createCallbackFcn(app, @annotationsTableUIFigureCloseRequest, true);
            app.UITable = uitable(app.annotationsTableUIFigure);
            app.UITable.ColumnName = {'Channel'; 'Type'; 'Start (s)'; 'End (s)'; 'Scope'; 'Creation Time'; 'Username'; 'Comments'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GoToSelectedAnnotationLabel = uilabel(app.annotationsTableUIFigure);
            app.GoToSelectedAnnotationLabel.Text = {'GoTo '; 'Selected Annotation'};
            % Show the figure after all components are created
            app.annotationsTableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotationsTable(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.annotationsTableUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.annotationsTableUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotationsTableUIFigure)
        end
    end
end