classdef badSegmentsEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        badSegmentEdit              matlab.ui.Figure
        badSegmentsTable            matlab.ui.control.Table
        SelectChannelDropDown       matlab.ui.control.DropDown
        SelectChannelDropDownLabel  matlab.ui.control.Label
        EndTextArea                 matlab.ui.control.TextArea
        EndsTextAreaLabel_2         matlab.ui.control.Label
        StartTextArea               matlab.ui.control.TextArea
        StartsTextAreaLabel_2       matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        titleText                   matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        fs = 0;
        maxLengthS = 0;
        callingApp = [];
        selectedChannel = [];
        badSegmentsCell = {};
        selectedRow = 0;
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, fs, nSamples, channsList, badsegsList)
            app.maxLengthS = nSamples/fs;
            app.fs = fs;
            app.callingApp = caller;
            app.SelectChannelDropDown.Items = ['All'; channsList];
            app.selectedChannel = app.SelectChannelDropDown.Value;

            nrBadSegments = size(badsegsList,1);
            for bsi = 1:nrBadSegments
                badEntree = {badsegsList{bsi,1}, badsegsList{bsi,3}, badsegsList{bsi,4}};
                app.badSegmentsCell = cat(1, app.badSegmentsCell, badEntree);
            end
            app.badSegmentsTable.Data = app.badSegmentsCell;
            app.badSegmentsTable.ColumnFormat = {'char', 'numeric', 'numeric'};

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            newBadStart = str2double(app.StartTextArea.Value);
            newBadEnd = str2double(app.EndTextArea.Value);
            if newBadStart  <= 0
                newBadStart = 0;
            end
            if newBadEnd < newBadStart
                newBadEnd = newBadStart + 1;
            end
            if newBadEnd > app.maxLengthS
               newBadEnd = app.maxLengthS;
            end

            newBadEntree = {app.selectedChannel, newBadStart, newBadEnd};
            app.badSegmentsCell = cat(1, app.badSegmentsCell, newBadEntree);
            app.badSegmentsTable.Data = app.badSegmentsCell;
            app.badSegmentsTable.ColumnFormat = {'char', 'numeric', 'numeric'};

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.badSegmentsCell(app.selectedRow,:) = [];
            app.badSegmentsTable.Data = app.badSegmentsCell;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.badSegmentsCell = app.badSegmentsTable.Data;
            app.callingApp.saveBadSectors(app.badSegmentsCell);
            app.delete;
        end

        % Value changed function: SelectChannelDropDown
        function SelectChannelDropDownValueChanged(app, event)
            app.selectedChannel = app.SelectChannelDropDown.Value;
        end

        % Cell edit callback: badSegmentsTable
        function badSegmentsTableCellEdit(app, event)
            indices = event.Indices;
            newBadStart = app.badSegmentsTable.Data{indices(1),2};
            newBadEnd = app.badSegmentsTable.Data{indices(1),3};
            oldBadStart = app.badSegmentsCell{indices(1),2};
            oldBadEnd = app.badSegmentsCell{indices(1),3};

            if (newBadStart  < 0) || (newBadStart  > newBadEnd)
                newBadStart = oldBadStart;
            end
            if (newBadEnd < newBadStart) || (newBadEnd > app.maxLengthS)
                newBadEnd = oldBadEnd;
            end

            app.badSegmentsCell{indices(1),2} = newBadStart;
            app.badSegmentsCell{indices(1),3} = newBadEnd;
            app.badSegmentsTable.Data = app.badSegmentsCell;
        end

        % Cell selection callback: badSegmentsTable
        function badSegmentsTableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.badSegmentEdit = uifigure('Visible', 'off');
            app.badSegmentEdit.Name = 'Bad Sector Editor';
            app.badSegmentEdit.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.DeleteButton = uibutton(app.badSegmentEdit, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddButton = uibutton(app.badSegmentEdit, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.titleText = uilabel(app.badSegmentEdit);
            app.titleText.Text = 'Bad EEG Segment Editor';
            app.SaveButton = uibutton(app.badSegmentEdit, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StartsTextAreaLabel_2 = uilabel(app.badSegmentEdit);
            app.StartsTextAreaLabel_2.Text = 'Start (s)';
            app.StartTextArea = uitextarea(app.badSegmentEdit);
            app.EndsTextAreaLabel_2 = uilabel(app.badSegmentEdit);
            app.EndsTextAreaLabel_2.Text = 'End (s)';
            app.EndTextArea = uitextarea(app.badSegmentEdit);
            app.SelectChannelDropDownLabel = uilabel(app.badSegmentEdit);
            app.SelectChannelDropDownLabel.Text = 'Select Channel';
            app.SelectChannelDropDown = uidropdown(app.badSegmentEdit);
            app.SelectChannelDropDown.Items = {'ChannA', 'ChannB', 'ChannC', 'ChannD'};
            app.SelectChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectChannelDropDownValueChanged, true);
            app.SelectChannelDropDown.Value = 'ChannA';
            app.badSegmentsTable = uitable(app.badSegmentEdit);
            app.badSegmentsTable.ColumnName = {'Channel'; 'Start (s)'; 'End (s)'};
            app.badSegmentsTable.RowName = {};
            app.badSegmentsTable.ColumnSortable = [false true true];
            app.badSegmentsTable.ColumnEditable = [false true true];
            app.badSegmentsTable.CellEditCallback = createCallbackFcn(app, @badSegmentsTableCellEdit, true);
            app.badSegmentsTable.CellSelectionCallback = createCallbackFcn(app, @badSegmentsTableCellSelection, true);
            % Show the figure after all components are created
            app.badSegmentEdit.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = badSegmentsEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.badSegmentEdit)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.badSegmentEdit)
        end
    end
end