classdef channelSelectorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        channelsList         matlab.ui.Figure
        OKButton             matlab.ui.control.Button
        SelectChannelsLabel  matlab.ui.control.Label
        selChannsTree        matlab.ui.container.CheckBoxTree
        AllNode              matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        selectedChannels = {};
        callerApp = [];
    end
    
    
    methods (Access = private)

        function populateChannList(app, allChannelsList, currChannsList)
            a = app.AllNode.Children;
            a.delete;
            for chi = 1:length(allChannelsList)
                chLabel = allChannelsList{chi};
                % Create Node
                newNode = uitreenode(app.AllNode);
                newNode.Text = chLabel;
            end

            preSelChanns = ismember(allChannelsList, currChannsList);
            preSelChanns = [false; preSelChanns];
            app.selChannsTree.CheckedNodes = app.selChannsTree.CheckedNodes(preSelChanns);
        end

        function saveSelectedMtgs(app)
            app.selectedChannels = {};
            checkedNodes = app.selChannsTree.CheckedNodes;
            if not(isempty(checkedNodes))
                for cni = 1:size(checkedNodes,1)
                    app.selectedChannels = ...
                        cat(1, app.selectedChannels, checkedNodes(cni).Text);
                end
                delIdx = find(ismember(app.selectedChannels, "All"));
                app.selectedChannels(delIdx) = [];
                tSel = app.selectedChannels;
            end
        end

        function toggleComponents(app, enableFlag)
            if enableFlag
                flag = 'On';
            else
                flag = 'Off';
            end
            set(app.channelsList.Children, 'Enable', flag)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, allChannelsList, currChannsList)
            app.callerApp = callerApp;
            app.populateChannList(allChannelsList, currChannsList);
        end

        % Selection changed function: selChannsTree
        function selChannsTreeSelectionChanged(app, event)
            selectedNodes = app.selChannsTree.SelectedNodes;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.saveSelectedMtgs();
            app.callerApp.updateChannels(app.selectedChannels);
            app.toggleComponents(false);pause(1);
            app.toggleComponents(true);
            app.delete % Shut down the entire app
            pause(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.channelsList = uifigure('Visible', 'off');
            app.channelsList.Name = 'Channel Selector';
            app.channelsList.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.selChannsTree = uitree(app.channelsList, 'checkbox');
            app.selChannsTree.SelectionChangedFcn = createCallbackFcn(app, @selChannsTreeSelectionChanged, true);
            app.AllNode = uitreenode(app.selChannsTree);
            app.AllNode.Text = 'All';
            % Assign Checked Nodes
            app.selChannsTree.CheckedNodes = [app.AllNode];
            app.SelectChannelsLabel = uilabel(app.channelsList);
            app.SelectChannelsLabel.Text = 'Select Channels';
            app.OKButton = uibutton(app.channelsList, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.channelsList.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channelSelectorGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.channelsList)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.channelsList)
        end
    end
end