classdef eventEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventEditorUIFigure             matlab.ui.Figure
        AddSaveButton                   matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        MarkEditorPanel                 matlab.ui.container.Panel
        EndTimesSpinner                 matlab.ui.control.Spinner
        StartTimesSpinner               matlab.ui.control.Spinner
        usernameVal                     matlab.ui.control.Label
        usernameLabel                   matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        MarkedChannelDropDown           matlab.ui.control.DropDown
        MarkedChannelDropDownLabel      matlab.ui.control.Label
        SelectEventTypeDropDown         matlab.ui.control.DropDown
        SelectEventTypeLabel            matlab.ui.control.Label
        creationTimeVal                 matlab.ui.control.Label
        creationTimeLabel               matlab.ui.control.Label
        MultichannelAnnotationCheckBox  matlab.ui.control.CheckBox
        durationVal                     matlab.ui.control.Label
        duration                        matlab.ui.control.Label
        endTimeLabel                    matlab.ui.control.Label
        startTimeLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp; %annotateHFO
        fs;
        panelNr = 0;
        editFlag = false();
        mark = [];
    end
    
    properties (Access = public)
        %mark = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, mtgLabels, annotTypes, tempMark, tempMarkIdx, panelNr, fs)
            
            app.EventEditorUIFigure.Position(1:2) = [500 500];

            % Store main app object
            app.callingApp = caller;
            app.fs = fs;
            app.panelNr = panelNr;
            
            %% Channel selector
            eventCh = tempMark{1};
            delIdx = ismember(mtgLabels, eventCh);
            if size(mtgLabels,1) > 1
                mtgLabels(delIdx) = [];
                mtgLabels = cat(1, eventCh, mtgLabels);
            end
            app.SelectEventTypeDropDown.Items = {};
            app.MarkedChannelDropDown.Items = mtgLabels;

            %% Event selector
            eventTypesList = annotTypes; %%{'Ripple'; 'FR'; 'HFO'; 'IED'};
            eventType = tempMark{2};
            delIdx = ismember(eventTypesList, eventType);
            eventTypesList(delIdx) = [];
            eventTypesList = cat(1, eventType, eventTypesList);
            app.SelectEventTypeDropDown.Items = {};
            app.SelectEventTypeDropDown.Items = eventTypesList;

            %% Show times
            app.mark.idx = tempMarkIdx;
            app.mark.channel = tempMark{1};
            app.mark.type = tempMark{2};
            app.mark.startTime = tempMark{3};
            app.mark.endTime = tempMark{4};
            app.mark.startSample = tempMark{5};
            app.mark.endSample = tempMark{6};
            app.mark.comments = tempMark{7};            
            app.mark.channSpec = tempMark{8};
            app.mark.creationTime = tempMark{9};

            %% Update GUI values
%             app.startTimeVal.Text = num2str(app.mark.startTime);
%             app.endTimeVal.Text = num2str(app.mark.endTime);
            app.StartTimesSpinner.Value = app.mark.startTime;
            app.EndTimesSpinner.Value = app.mark.endTime;
            app.durationVal.Text = num2str(round(1000*(app.mark.endTime-app.mark.startTime)));
            app.creationTimeVal.Text = app.mark.creationTime;
            app.CommentsTextArea.Value = app.mark.comments;
            app.MultichannelAnnotationCheckBox.Value = not(app.mark.channSpec);
            app.usernameVal.Text = tempMark{10};

        end

        % Button pushed function: AddSaveButton
        function AddSaveButtonPushed(app, event)
            %% Update mark values
            app.mark.channel = app.MarkedChannelDropDown.Value;
            app.mark.type = app.SelectEventTypeDropDown.Value;
            app.mark.startTime = double(app.StartTimesSpinner.Value);
            app.mark.endTime = double(app.EndTimesSpinner.Value);
            app.mark.startSample = int32(app.mark.startTime*app.fs);
            app.mark.endSample = int32(app.mark.endTime*app.fs);
            app.mark.comments = convertCharsToStrings(app.CommentsTextArea.Value);
            app.mark.channSpec = not(app.MultichannelAnnotationCheckBox.Value);
            if app.editFlag
                t = datetime;
                timeStr = strcat(num2str(t.Day), '.', num2str(t.Month), '.',num2str(t.Year));
                timeStr = strcat(timeStr, '__',num2str(t.Hour), ':',num2str(t.Minute), ':',num2str(round(t.Second)));
                app.mark.creationTime = timeStr;
            end

            %% Save mark
            app.callingApp.saveMark(app.mark, app.panelNr);
            app.delete;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.callingApp.deleteMark(app.mark);
            app.delete;
        end

        % Key press function: EventEditorUIFigure
        function EventEditorUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'return')
                AddSaveButtonPushed(app, event);
            elseif strcmp(key, 'delete')
                RemoveButtonPushed(app, event);
            elseif strcmp(key, 'escape')
                app.delete();
            end
        end

        % Value changed function: CommentsTextArea
        function CommentsTextAreaValueChanged(app, event)
            app.editFlag = true();
        end

        % Value changed function: SelectEventTypeDropDown
        function SelectEventTypeDropDownValueChanged(app, event)
            app.editFlag = true();            
        end

        % Value changed function: MarkedChannelDropDown
        function MarkedChannelDropDownValueChanged(app, event)
            app.editFlag = true();
        end

        % Value changed function: MultichannelAnnotationCheckBox
        function MultichannelAnnotationCheckBoxValueChanged(app, event)
            app.editFlag = true();
        end

        % Value changed function: StartTimesSpinner
        function StartTimesSpinnerValueChanged(app, event)
            newStartVal = app.StartTimesSpinner.Value;
            oldEndVal = app.EndTimesSpinner.Value;
            newDuration =  oldEndVal - newStartVal;
            if newDuration >= 0.025
                app.durationVal.Text = num2str(round(1000*(newDuration)));
            else
                app.StartTimesSpinner.Value = oldEndVal-0.025;
                newDuration = app.EndTimesSpinner.Value - app.StartTimesSpinner.Value;
                app.durationVal.Text = num2str(round(1000*(newDuration)));
            end
        end

        % Value changed function: EndTimesSpinner
        function EndTimesSpinnerValueChanged(app, event)
            newEndVal = app.EndTimesSpinner.Value;
            oldStartVal = app.StartTimesSpinner.Value;
            newDuration =  newEndVal - oldStartVal;
            if newDuration >= 0.025
                app.durationVal.Text = num2str(round(1000*(newDuration)));
            else
                app.EndTimesSpinner.Value = oldStartVal+0.025;
                newDuration = app.EndTimesSpinner.Value - app.StartTimesSpinner.Value;
                app.durationVal.Text = num2str(round(1000*(newDuration)));
            end
        end

        % Close request function: EventEditorUIFigure
        function EventEditorUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EventEditorUIFigure = uifigure('Visible', 'off');
            app.EventEditorUIFigure.Name = 'Event Editor';
            app.EventEditorUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.EventEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventEditorUIFigureCloseRequest, true);
            app.EventEditorUIFigure.KeyPressFcn = createCallbackFcn(app, @EventEditorUIFigureKeyPress, true);
            app.MarkEditorPanel = uipanel(app.EventEditorUIFigure);
            app.MarkEditorPanel.Title = 'Mark Editor';
            app.startTimeLabel = uilabel(app.MarkEditorPanel);
            app.startTimeLabel.Text = 'Start Time (s)';
            app.endTimeLabel = uilabel(app.MarkEditorPanel);
            app.endTimeLabel.Text = 'End Time (s)';
            app.duration = uilabel(app.MarkEditorPanel);
            app.duration.Text = 'Duration (ms)';
            app.durationVal = uilabel(app.MarkEditorPanel);
            app.durationVal.Text = '';
            app.MultichannelAnnotationCheckBox = uicheckbox(app.MarkEditorPanel);
            app.MultichannelAnnotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultichannelAnnotationCheckBoxValueChanged, true);
            app.MultichannelAnnotationCheckBox.Text = {'Multichannel'; 'Annotation'};
            app.MultichannelAnnotationCheckBox.Value = true;
            app.creationTimeLabel = uilabel(app.MarkEditorPanel);
            app.creationTimeLabel.Text = 'Creation Time';
            app.creationTimeVal = uilabel(app.MarkEditorPanel);
            app.creationTimeVal.Text = '';
            app.SelectEventTypeLabel = uilabel(app.MarkEditorPanel);
            app.SelectEventTypeLabel.Text = 'Select Event Type: ';
            app.SelectEventTypeDropDown = uidropdown(app.MarkEditorPanel);
            app.SelectEventTypeDropDown.Items = {'Ripple', 'FR', 'HFO', 'IED'};
            app.SelectEventTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventTypeDropDownValueChanged, true);
            app.SelectEventTypeDropDown.Value = 'Ripple';
            app.MarkedChannelDropDownLabel = uilabel(app.MarkEditorPanel);
            app.MarkedChannelDropDownLabel.Text = 'Marked Channel: ';
            app.MarkedChannelDropDown = uidropdown(app.MarkEditorPanel);
            app.MarkedChannelDropDown.Items = {};
            app.MarkedChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkedChannelDropDownValueChanged, true);
            app.MarkedChannelDropDown.Value = {};
            app.CommentsTextAreaLabel = uilabel(app.MarkEditorPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.MarkEditorPanel);
            app.CommentsTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentsTextAreaValueChanged, true);
            app.usernameLabel = uilabel(app.MarkEditorPanel);
            app.usernameLabel.Text = 'Username';
            app.usernameVal = uilabel(app.MarkEditorPanel);
            app.usernameVal.Text = '';
            app.StartTimesSpinner = uispinner(app.MarkEditorPanel);
            app.StartTimesSpinner.Step = 0.1;
            app.StartTimesSpinner.ValueDisplayFormat = '%.3f';
            app.StartTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @StartTimesSpinnerValueChanged, true);
            app.EndTimesSpinner = uispinner(app.MarkEditorPanel);
            app.EndTimesSpinner.Step = 0.1;
            app.EndTimesSpinner.ValueDisplayFormat = '%.3f';
            app.EndTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @EndTimesSpinnerValueChanged, true);
            app.RemoveButton = uibutton(app.EventEditorUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AddSaveButton = uibutton(app.EventEditorUIFigure, 'push');
            app.AddSaveButton.ButtonPushedFcn = createCallbackFcn(app, @AddSaveButtonPushed, true);
            app.AddSaveButton.Text = 'Add/Save';
            % Show the figure after all components are created
            app.EventEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eventEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventEditorUIFigure)
        end
    end
end