classdef initAnnotateHFO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        settingsUI                    matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        AnnotationsMenu               matlab.ui.container.Menu
        ProfilesMenu                  matlab.ui.container.Menu
        reviewEndTimeSpinner          matlab.ui.control.Spinner
        EndsSpinnerLabel              matlab.ui.control.Label
        reviewStartTimeSpinner        matlab.ui.control.Spinner
        StartsSpinnerLabel            matlab.ui.control.Label
        WaveletAnalysisPanel          matlab.ui.container.Panel
        waveletStepsText              matlab.ui.control.TextArea
        MaxFreqLabel_3                matlab.ui.control.Label
        waveletMaxFreqText            matlab.ui.control.TextArea
        waveletMinFreqText            matlab.ui.control.TextArea
        MaxFreqLabel_2                matlab.ui.control.Label
        MaxFreqLabel                  matlab.ui.control.Label
        noEEGAlarm                    matlab.ui.control.Label
        wrongSR_Label                 matlab.ui.control.Label
        startEEGReviewerButton        matlab.ui.control.Button
        DefinedeventstomarkPanel      matlab.ui.container.Panel
        definedEventsListBox          matlab.ui.control.ListBox
        FIRFilterSelectionPanel       matlab.ui.container.Panel
        NotchFiltersButton            matlab.ui.control.Button
        ShowcharacteristicsButton     matlab.ui.control.StateButton
        OrderLabel                    matlab.ui.control.Label
        HighLabel                     matlab.ui.control.Label
        LowLabel                      matlab.ui.control.Label
        Bandpass4Order                matlab.ui.control.EditField
        Bandpass4High                 matlab.ui.control.EditField
        Bandpass4Low                  matlab.ui.control.EditField
        Bandpass4                     matlab.ui.control.Label
        Bandpass3Order                matlab.ui.control.EditField
        Bandpass3High                 matlab.ui.control.EditField
        Bandpass3Low                  matlab.ui.control.EditField
        Bandpass3                     matlab.ui.control.Label
        Bandpass2Order                matlab.ui.control.EditField
        Bandpass2High                 matlab.ui.control.EditField
        Bandpass2Low                  matlab.ui.control.EditField
        Bandpass2                     matlab.ui.control.Label
        Bandpass1Order                matlab.ui.control.EditField
        Bandpass1High                 matlab.ui.control.EditField
        Bandpass1Low                  matlab.ui.control.EditField
        Bandpass1                     matlab.ui.control.Label
        ChannelsSettingsPanel         matlab.ui.container.Panel
        TotalDurationsEditField       matlab.ui.control.NumericEditField
        TotalDurationsEditFieldLabel  matlab.ui.control.Label
        EEGTimeselectionLabel         matlab.ui.control.Label
        SelectScalpEEGMontageLabel    matlab.ui.control.Label
        selectMontageDropDown         matlab.ui.control.DropDown
        EEGTypeButtonGroup            matlab.ui.container.ButtonGroup
        intracranialButton            matlab.ui.control.RadioButton
        scalpButton                   matlab.ui.control.RadioButton
        selectChannelsLabel           matlab.ui.control.Label
        selChannsTree                 matlab.ui.container.CheckBoxTree
        allNode                       matlab.ui.container.TreeNode
        helloUserTxt                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        standalone = false();
        eegFilepath;
        permits = [];

        % Settings
        eegFileReadOK;
        eegSR;
        eegDurationSec;
        availableUnipolarLabels;
        longBipolarLabels;
        transverseBipolarLabels;
        availableMtgLabels;
        selectedMtgLabels;
        annotTypesAndColorsList;
        filterValues;
        waveletValues;
        reviewStartSec;
        reviewEndSec;
        notchSettings;
        initSettings;

        % Submenus
        profilesFilename;
        existingUserProfiles;
        activeProfileName;

        % Apps Handles
        annotsSetupHdl;
        profilesSetupHdl;
        filtersCharacterizationAppHdl;
        notchFltrsHdl;

    end
    
    methods (Access = private)
        
        function initializeVariables(app)
            
            app.eegFileReadOK = false();

            % create profiles path
            app.profilesFilename = 'profiles.mat';
            app.activeProfileName = {''};


            app.longBipolarLabels = {'Fp1-F7'; 'F7-T7'; 'T7-P7'; 'P7-O1'; 'F7-T3'; 'T3-T5'; 'T5-O1';...
            'Fp2-F8'; 'F8-T8'; 'T8-P8'; 'P8-O2'; 'F8-T4'; 'T4-T6'; 'T6-O2'; 'Fp1-F3'; 'F3-C3'; 'C3-P3';...
            'P3-O1'; 'Fp2-F4'; 'F4-C4'; 'C4-P4'; 'P4-O2'; 'FZ-CZ'; 'CZ-PZ'};
            app.transverseBipolarLabels = {'F7-F3'; 'F3-FZ'; 'FZ-F4'; 'F4-F8';...
            'T3-C3'; 'C3-CZ'; 'CZ-C4'; 'C4-T4'; 'T5-P3'; 'P3-PZ';...
            'PZ-P4'; 'P4-T6'};

            app.wrongSR_Label.Visible = 'off';

            % save default events to mark to variable
            app.annotTypesAndColorsList = {...
            'IED', [0.6353    0.0784    0.1843];...
            'HFO', [0    0.4471    0.7412];...
            'Ripple', [1.0000    0.4118    0.1608];...
            'FR', [1     0     1];...
            'Noise', [0.5 0.08 0.2];...
            'Bad', [1 0.41    0.16];...
            };
            app.definedEventsListBox.Items = app.annotTypesAndColorsList(:,1);

            % save default filter values to variable
            app.filterValues =...
            [str2num(app.Bandpass1Low.Value), str2num(app.Bandpass1High.Value), str2num(app.Bandpass1Order.Value);...
            str2num(app.Bandpass2Low.Value), str2num(app.Bandpass2High.Value), str2num(app.Bandpass2Order.Value);...
            str2num(app.Bandpass3Low.Value), str2num(app.Bandpass3High.Value), str2num(app.Bandpass3Order.Value);...
            str2num(app.Bandpass4Low.Value), str2num(app.Bandpass4High.Value), str2num(app.Bandpass4Order.Value)];

            app.waveletValues = [str2num(app.waveletMinFreqText.Value{1}), str2num(app.waveletMaxFreqText.Value{1}), str2num(app.waveletStepsText.Value{1})];

            app.reviewStartSec = 0;
            app.reviewEndSec = 60;

            app.notchSettings.order = [];
            app.notchSettings.freqs = [];
            app.notchSettings.channs = [];
            app.notchSettings.europe = false();

            [thisFilePath,~,~] = fileparts(mfilename('fullpath'));
            settingsFN = strcat(thisFilePath, '\', 'settings.mat');
            if isfile(settingsFN)
                storedSettings = load(settingsFN);
                storedSettings.settings.selMtgLabels = [];
                storedSettings.settings.notchSettings.channs = [];
                storedSettings = storedSettings.settings;
                app.notchSettings = storedSettings.notchSettings;
            end
        end

        function updateAvailableMontageTypes(app)
            if strcmp(app.EEGTypeButtonGroup.SelectedObject.Text, 'Scalp')
                % Select Montage
                app.selectMontageDropDown.Items = {'Longitudinal Bipolar', 'Transverse Bipolar', 'Physical Reference'};
                app.selectMontageDropDown.Value = 'Longitudinal Bipolar';
            elseif strcmp(app.EEGTypeButtonGroup.SelectedObject.Text, 'Intracranial')
                app.selectMontageDropDown.Items = {'Intracranial Bipolar', 'Physical Reference'};
                app.selectMontageDropDown.Value = 'Intracranial Bipolar';
            end
        end

        function updateAvailableMtgLabels(app)
            if app.eegFileReadOK
                % get channel combinations for corresponding montage
                app.toggleComponents(false);
                if strcmp(app.EEGTypeButtonGroup.SelectedObject.Text, 'Scalp')
                    if strcmp(app.selectMontageDropDown.Value, 'Longitudinal Bipolar')
                        app.availableMtgLabels = getAvailableScalpMtgLabels(app.availableUnipolarLabels, app.longBipolarLabels);
                        if not(isempty(app.availableMtgLabels))
                            app.availableMtgLabels = app.availableMtgLabels(:,1);
                        end
                    elseif strcmp(app.selectMontageDropDown.Value, 'Transverse Bipolar')
                        app.availableMtgLabels = getAvailableScalpMtgLabels(app.availableUnipolarLabels, app.transverseBipolarLabels);
                        if not(isempty(app.availableMtgLabels))
                            app.availableMtgLabels = app.availableMtgLabels(:,1);
                        end
                    elseif strcmp(app.selectMontageDropDown.Value, 'Physical Reference')
                        app.availableMtgLabels = app.availableUnipolarLabels;
                    end
                else
                    if strcmp(app.selectMontageDropDown.Value, 'Intracranial Bipolar')
                        app.availableMtgLabels = {};
                        [unipolarIntraMtg, bipolarIntraMtg] = getIntracranialBipolarMontageLabels(app.availableUnipolarLabels);
                        app.availableMtgLabels = cell(size(bipolarIntraMtg,1),1);
                        for imi = 1:size(bipolarIntraMtg,1)
                            app.availableMtgLabels{imi} = bipolarIntraMtg(imi).montageName;
                        end
                    elseif strcmp(app.selectMontageDropDown.Value, 'Physical Reference')
                        app.availableMtgLabels = app.availableUnipolarLabels;
                    end
                end
                app.toggleComponents(true);
    
                % Populate list of montage labels
                a = app.allNode.Children;
                a.delete;
                for chi = 1:size(app.availableMtgLabels,1)
                    chLabel = app.availableMtgLabels{chi};
                    newNode = uitreenode(app.allNode);
                    newNode.Text = chLabel;
                    app.selChannsTree.CheckedNodes = [app.selChannsTree.CheckedNodes; newNode];
                end
                
                % select all labels
                checkedNodes = app.selChannsTree.CheckedNodes;
                app.selectedMtgLabels = cell(size(checkedNodes,1),1);
                for ni = 1:size(checkedNodes,1)
                    app.selectedMtgLabels{ni} = checkedNodes(ni).Text;
                end
                app.selectedMtgLabels(ismember(app.selectedMtgLabels, 'All')) = [];                
            end
        end

        function setReviewTimeSelection(app)
            app.EEGTimeselectionLabel.Visible = 'on';
            app.TotalDurationsEditFieldLabel.Visible = 'on';
            app.TotalDurationsEditField.Editable = 'off';
            app.TotalDurationsEditField.Enable = 'on';
            app.TotalDurationsEditField.Visible = 'on';
            app.StartsSpinnerLabel.Visible = 'on';
            app.reviewStartTimeSpinner.Editable = 'on';
            app.reviewStartTimeSpinner.Enable = 'on';
            app.reviewStartTimeSpinner.Visible = 'on';
            app.EndsSpinnerLabel.Visible = 'on';
            app.reviewEndTimeSpinner.Editable = 'on';
            app.reviewEndTimeSpinner.Enable = 'on';
            app.reviewEndTimeSpinner.Visible = 'on';

            app.TotalDurationsEditField.Value = app.eegDurationSec;
            app.reviewStartTimeSpinner.Value = 0;
            app.reviewEndTimeSpinner.Value = app.eegDurationSec;
            app.reviewStartSec = 0;
            app.reviewEndSec = app.eegDurationSec;

        end

        function checkCorrectFilterValues(app)
            app.filterValues =...
            [str2num(app.Bandpass1Low.Value), str2num(app.Bandpass1High.Value), str2num(app.Bandpass1Order.Value);...
            str2num(app.Bandpass2Low.Value), str2num(app.Bandpass2High.Value), str2num(app.Bandpass2Order.Value);...
            str2num(app.Bandpass3Low.Value), str2num(app.Bandpass3High.Value), str2num(app.Bandpass3Order.Value);...
            str2num(app.Bandpass4Low.Value), str2num(app.Bandpass4High.Value), str2num(app.Bandpass4Order.Value)];

            if app.eegFileReadOK
                app.filterValues(app.filterValues < 0.5) = 0.5;
                app.filterValues(app.filterValues > app.eegSR/2) = app.eegSR/2;
    
                app.Bandpass1Low.Value = num2str(app.filterValues(1,1));
                app.Bandpass1High.Value = num2str(app.filterValues(1,2));
                app.Bandpass1Order.Value = num2str(app.filterValues(1,3));
    
                app.Bandpass2Low.Value = num2str(app.filterValues(2,1));
                app.Bandpass2High.Value = num2str(app.filterValues(2,2));
                app.Bandpass2Order.Value = num2str(app.filterValues(2,3));
    
                app.Bandpass3Low.Value = num2str(app.filterValues(3,1));
                app.Bandpass3High.Value = num2str(app.filterValues(3,2));
                app.Bandpass3Order.Value = num2str(app.filterValues(3,3));
    
                app.Bandpass4Low.Value = num2str(app.filterValues(4,1));
                app.Bandpass4High.Value = num2str(app.filterValues(4,2));
                app.Bandpass4Order.Value = num2str(app.filterValues(4,3));
            end
        end

        function checkCorrectWvltValues(app)
            app.waveletValues = [str2num(app.waveletMinFreqText.Value{1}), str2num(app.waveletMaxFreqText.Value{1}), str2num(app.waveletStepsText.Value{1})];
            if app.eegFileReadOK
                app.waveletValues(app.waveletValues < 0) = 0;
                app.waveletValues(app.waveletValues > app.eegSR/2) = app.eegSR/2;
                app.waveletMinFreqText.Value = num2str(app.waveletValues(1));
                app.waveletMaxFreqText.Value = num2str(app.waveletValues(2));
                app.waveletStepsText.Value = num2str(app.waveletValues(3));
            end
        end

        function readExistingProfiles(app)
            app.existingUserProfiles = {};
            if isfile(app.profilesFilename)
                load(app.profilesFilename, 'savedProfiles');
                app.existingUserProfiles = savedProfiles;
            end
        end


        function toggleComponents(app, enableFlag)
            flag = 'Off';
            if enableFlag
                flag = 'On';
            end
            set(app.settingsUI.Children, 'Enable', flag)
        end
    end
    
    methods (Access = public)

        function saveAnnotationSettings(app, eventsAndColorList)
            app.annotTypesAndColorsList = eventsAndColorList;
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end

            % Save annotation types to profile
            if isfile(app.profilesFilename) && ~isempty(app.activeProfileName{1})
                load(app.profilesFilename, 'savedProfiles');
                prflIdx = find(ismember(savedProfiles(:,1), app.activeProfileName), 1);
                profile = savedProfiles(prflIdx,2);
                profile = profile{1};
                profile.annotTypesAndColorsList = app.annotTypesAndColorsList;
                savedProfiles{prflIdx,2} = profile;
                save(app.profilesFilename, 'savedProfiles');
            end
            app.definedEventsListBox.Items = app.annotTypesAndColorsList(:,1);
        end

        function saveUserProfile(app, sourceHdl, activeProfile)
            if ~isempty(sourceHdl) && isvalid(sourceHdl)
                sourceHdl.delete;
            end

            app.activeProfileName = activeProfile;
            profile.name = activeProfile;
            profile.eegFilepath = app.eegFilepath;
            profile.eegType = app.EEGTypeButtonGroup.SelectedObject.Text;
            profile.mtgType = app.selectMontageDropDown.Value;
            profile.selMtgLabels = app.selectedMtgLabels;
            profile.filterValues = app.filterValues;
            profile.waveletValues = app.waveletValues;
            profile.annotTypesAndColorsList = app.annotTypesAndColorsList;
            newProfile = {app.activeProfileName{1},  profile};

            if isempty(app.existingUserProfiles)
                app.existingUserProfiles = newProfile;
            elseif isempty(find(ismember(app.existingUserProfiles(:,1), app.activeProfileName), 1))
                app.existingUserProfiles = cat(1, app.existingUserProfiles, newProfile);
            else
                % should never occur
                prflIdx = find(ismember(app.existingUserProfiles(:,1), app.activeProfile), 1);
                app.existingUserProfiles{prflIdx,:} = newProfile;
                "Editing Profile"
            end

            savedProfiles = app.existingUserProfiles;
            save(app.profilesFilename, 'savedProfiles');

            app.helloUserTxt.Text = strcat("Hello, ", app.activeProfileName{1});
            app.helloUserTxt.Visible = "on";
        end

        function loadUserProfile(app, sourceHdl, activeProfile)
            if ~isempty(sourceHdl) && isvalid(sourceHdl)
                sourceHdl.delete;
            end
            prflIdx = find(ismember(app.existingUserProfiles(:,1), activeProfile), 1);
            loadedProfile = app.existingUserProfiles(prflIdx,2);
            profile = loadedProfile{1};

            app.activeProfileName = profile.name;
            
            % Update EEG type
            app.scalpButton.Value = true;
            if strcmp(profile.eegType, 'Intracranial')
                app.scalpButton.Value = false;
                app.intracranialButton.Value = true;
            end
               
            % Update mtg labels
            app.updateAvailableMontageTypes();
            app.selectMontageDropDown.Value = profile.mtgType;
            app.selectedMtgLabels = profile.selMtgLabels;
            app.updateAvailableMtgLabels();
            
            % Update filter values
            app.filterValues = profile.filterValues;
            app.Bandpass1Low.Value = num2str(app.filterValues(1,1));
            app.Bandpass1High.Value = num2str(app.filterValues(1,2));
            app.Bandpass1Order.Value = num2str(app.filterValues(1,3));
            
            app.Bandpass2Low.Value = num2str(app.filterValues(2,1));
            app.Bandpass2High.Value = num2str(app.filterValues(2,2));
            app.Bandpass2Order.Value = num2str(app.filterValues(2,3));
            
            app.Bandpass3Low.Value = num2str(app.filterValues(3,1));
            app.Bandpass3High.Value = num2str(app.filterValues(3,2));
            app.Bandpass3Order.Value = num2str(app.filterValues(3,3));
            
            app.Bandpass4Low.Value = num2str(app.filterValues(4,1));
            app.Bandpass4High.Value = num2str(app.filterValues(4,2));
            app.Bandpass4Order.Value = num2str(app.filterValues(4,3));

            app.checkCorrectFilterValues();

            % Update wavelet values
            app.waveletMinFreqText.Value = num2str(app.waveletValues(1));
            app.waveletMaxFreqText.Value = num2str(app.waveletValues(2));
            app.waveletStepsText.Value = num2str(app.waveletValues(3));

            app.checkCorrectWvltValues();

            % Update annotation types
            app.annotTypesAndColorsList = profile.annotTypesAndColorsList;
            app.definedEventsListBox.Items = app.annotTypesAndColorsList(:,1);

            app.helloUserTxt.Text = strcat("Hello, ", app.activeProfileName{1});
            app.helloUserTxt.Visible = "on";
        end

        function deleteUserProfile(app, sourceHdl, activeProfile)
            if ~isempty(sourceHdl) && isvalid(sourceHdl)
                sourceHdl.delete;
            end
            prflIdx = find(ismember(app.existingUserProfiles(:,1), activeProfile), 1);
            app.existingUserProfiles(prflIdx,:) = [];

            savedProfiles = app.existingUserProfiles;
            save(app.profilesFilename, 'savedProfiles');
        end
        
        function notchSetupOK(app, notchSettings)
            app.notchSettings = notchSettings;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            disp('startupFcn');
            if not(app.standalone)
                [thisFilePath,~,~] = fileparts(mfilename('fullpath'));
                cd(thisFilePath);
                run('startupScript.m');
    
                desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
                mf = desktop.getMainFrame();
                mf.setMinimized(true);
            end

            clc;

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            app.settingsUI.Position(1) = screenWidth*0.4;
            app.settingsUI.Position(2) = screenHeight*0.2;
            
            app.permits = checkUsagePermission();

            app.initializeVariables();
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            app.noEEGAlarm.Visible = 'off';
            app.wrongSR_Label.Visible = 'off';
            % load last search path if available
            lastSearchpathFile = 'lsp.mat';
            if isfile(lastSearchpathFile)
                load(lastSearchpathFile, 'lastSearchpath');
                if isfolder(lastSearchpath)
                    [filename, lastSearchpath] = uigetfile({'*.*'}, 'Select an EEG file', lastSearchpath);
                else
                    [filename, lastSearchpath] = uigetfile({'*.*'; '*.trc'}, 'Select an EEG file');
                end
            else
                [filename, lastSearchpath] = uigetfile({'*.*'; '*.trc'}, 'Select an EEG file');
            end

            if filename ~= 0
                save(lastSearchpathFile, 'lastSearchpath');
                app.eegFilepath = strcat(lastSearchpath, filename);
                
                % Read eeg file properties
                app.toggleComponents(false); pause(1);
                hdr = ft_read_header(app.eegFilepath);
                app.availableUnipolarLabels = hdr.label;
                app.eegSR = hdr.Fs;
                app.eegDurationSec = double(hdr.nSamples)/double(hdr.Fs);
                app.toggleComponents(true);
                
                [filepath, patName, ext] = fileparts(app.eegFilepath);
                spaceStr = "        ";
                spaceStr = strcat(spaceStr, spaceStr, spaceStr);
                app.settingsUI.Name = strcat("elpi Settings",...
                    spaceStr, patName, spaceStr, "Sampling Rate: ", num2str(app.eegSR), " Hz");


                if app.eegSR >= 1000
                    app.eegFileReadOK = true();

                    app.Bandpass1Low.Value = num2str(1);
                    app.Bandpass1High.Value = num2str(40);
                    app.Bandpass1Order.Value = num2str(512);
      
                    app.Bandpass2Low.Value = num2str(80);
                    app.Bandpass2High.Value = num2str(500);
                    app.Bandpass2Order.Value = num2str(512);
        
                    app.Bandpass3Low.Value = num2str(80);
                    app.Bandpass3High.Value = num2str(250);
                    app.Bandpass3Order.Value = num2str(512);
        
                    app.Bandpass4Low.Value = num2str(250);
                    app.Bandpass4High.Value = num2str(500);
                    app.Bandpass4Order.Value = num2str(512);
                else
                    app.eegFileReadOK = false();
                    app.wrongSR_Label.Text = {'Sampling Rate'; 'is too low!'};
                    app.wrongSR_Label.Visible = 'on';
                end

                app.updateAvailableMontageTypes();
                app.updateAvailableMtgLabels();
                app.setReviewTimeSelection();
                app.checkCorrectFilterValues();
                app.checkCorrectWvltValues();
            end
        end

        % Selection changed function: EEGTypeButtonGroup
        function EEGTypeButtonGroupSelectionChanged(app, event)
            app.updateAvailableMontageTypes();
            app.updateAvailableMtgLabels();
        end

        % Value changed function: selectMontageDropDown
        function selectMontageDropDownValueChanged(app, event)
            app.updateAvailableMtgLabels();
        end

        % Callback function: selChannsTree
        function selChannsTreeCheckedNodesChanged(app, event)
            checkedNodes = app.selChannsTree.CheckedNodes;
            app.selectedMtgLabels = cell(size(checkedNodes,1),1);
            for ni = 1:size(checkedNodes,1)
                app.selectedMtgLabels{ni} = checkedNodes(ni).Text;
            end
        end

        % Callback function: Bandpass1High, Bandpass1Low, Bandpass1Low, 
        % ...and 10 other components
        function FilterValueChanged(app, event)
            app.checkCorrectFilterValues();
        end

        % Value changed function: waveletMinFreqText
        function waveletMinFreqTextValueChanged(app, event)
            app.checkCorrectWvltValues();
        end

        % Button pushed function: startEEGReviewerButton
        function startEEGReviewerButtonPushed(app, event)
            if app.eegFileReadOK
                settings.profileName = app.activeProfileName;
                settings.eegFilepath = app.eegFilepath;
                settings.eegType = app.EEGTypeButtonGroup.SelectedObject.Text;
                settings.mtgType = app.selectMontageDropDown.Value;
                settings.selMtgLabels = app.selectedMtgLabels;
                settings.filterValues = app.filterValues;
                settings.annotTypesAndColorsList = app.annotTypesAndColorsList;
                settings.lastSelBigMtgs = [];
                settings.selMtgsToShowIdxs = [];
                settings.reviewStartSec = app.reviewStartSec;
                settings.reviewEndSec = app.reviewEndSec ;
                settings.yIntervBig = 0;
                settings.yIntervSmall = 0;
                settings.fltrBttnBigIdx = 1;
                settings.fltrBttnSmallIdx = 1;
                settings.eegSampleLocation = 1;
                settings.notchSettings = app.notchSettings;
                
                %% open review app and show progress bar
                uiprogressdlg(app.settingsUI,'Title','Please Wait',...
                'Message','Initializing elpi', 'Indeterminate','on');
                pause(.5)

                
                [thisFilePath,~,~] = fileparts(mfilename('fullpath'));
                settingsFN = strcat(thisFilePath, '\', 'settings.mat');
                save(settingsFN, 'settings')
                %system('annotateHFO.exe &');
                annotateHFO();

                app.delete % Shut down the entire app
            else
                app.noEEGAlarm.Visible = 'on';
            end
        end

        % Menu selected function: AnnotationsMenu
        function AnnotationsMenuSelected(app, event)
            if app.permits.annotsMenu
                if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                    app.annotsSetupHdl.delete;
                end
                app.annotsSetupHdl = annotationsSetup(app, app.annotTypesAndColorsList);
            end
        end

        % Menu selected function: ProfilesMenu
        function ProfilesMenuSelected(app, event)
            if app.permits.profilesMenu
                if ~isempty(app.profilesSetupHdl) && isvalid(app.profilesSetupHdl)
                    app.profilesSetupHdl.delete;
                end
    
                app.readExistingProfiles();
                existingProfileNames = {};
                if ~isempty(app.existingUserProfiles)
                    existingProfileNames = app.existingUserProfiles(:,1);
                end
                app.profilesSetupHdl = profilesSetup(app, existingProfileNames);
            end
        end

        % Window button down function: settingsUI
        function settingsUIWindowButtonDown(app, event)
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end
            if ~isempty(app.profilesSetupHdl) && isvalid(app.profilesSetupHdl)
                app.profilesSetupHdl.delete;
            end
            if ~isempty(app.filtersCharacterizationAppHdl) && isvalid(app.filtersCharacterizationAppHdl)
                app.filtersCharacterizationAppHdl.delete;
            end
            if ~isempty(app.notchFltrsHdl) && isvalid(app.notchFltrsHdl)
                app.notchFltrsHdl.delete;
            end
        end

        % Value changed function: ShowcharacteristicsButton
        function ShowcharacteristicsButtonValueChanged(app, event)
            if app.eegFileReadOK
                allFilterValues =...
                [str2num(app.Bandpass1Low.Value), str2num(app.Bandpass1High.Value), str2num(app.Bandpass1Order.Value);...
                str2num(app.Bandpass2Low.Value), str2num(app.Bandpass2High.Value), str2num(app.Bandpass2Order.Value);...
                str2num(app.Bandpass3Low.Value), str2num(app.Bandpass3High.Value), str2num(app.Bandpass3Order.Value);...
                str2num(app.Bandpass4Low.Value), str2num(app.Bandpass4High.Value), str2num(app.Bandpass4Order.Value)];
    
                app.filtersCharacterizationAppHdl = filtersCharacterization(app, allFilterValues, app.notchSettings, app.eegSR);
            else
                app.noEEGAlarm.Visible = 'on';
                pause(1);
                app.noEEGAlarm.Visible = 'off';
            end
        end

        % Close request function: settingsUI
        function settingsUICloseRequest(app, event)
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end
            if ~isempty(app.profilesSetupHdl) && isvalid(app.profilesSetupHdl)
                app.profilesSetupHdl.delete;
            end
            if ~isempty(app.filtersCharacterizationAppHdl) && isvalid(app.filtersCharacterizationAppHdl)
                app.filtersCharacterizationAppHdl.delete;
            end
            if ~isempty(app.notchFltrsHdl) && isvalid(app.notchFltrsHdl)
                app.notchFltrsHdl.delete;
            end

            delete(app)
            computerName = getenv('COMPUTERNAME');
            devComputers = {'LAPTOP-TFQFNF6U'; 'DLP'};
            if sum(ismember(devComputers, computerName)) == 0
                quit();
            end
        end

        % Value changed function: reviewStartTimeSpinner
        function reviewStartTimeSpinnerValueChanged(app, event)
            value = app.reviewStartTimeSpinner.Value;
            if value < 0
                value = 0;
            end
            if value + 60 > app.eegDurationSec
                value = app.eegDurationSec-60;
            end
            if value + 60 >= app.reviewEndSec
                app.reviewEndSec = value+60;
                app.reviewEndTimeSpinner.Value = app.reviewEndSec;
            end
            app.reviewStartSec = value;
            app.reviewStartTimeSpinner.Value = app.reviewStartSec;
        end

        % Value changed function: reviewEndTimeSpinner
        function reviewEndTimeSpinnerValueChanged(app, event)
            value = app.reviewEndTimeSpinner.Value;
            if value > app.eegDurationSec
                value = app.eegDurationSec;
            end
            if value -60 <= app.reviewStartSec
                value = app.reviewStartSec + 60;
            end
            app.reviewEndSec = value;
            app.reviewEndTimeSpinner.Value = app.reviewEndSec;
        end

        % Button pushed function: NotchFiltersButton
        function NotchFiltersButtonPushed(app, event)
            if app.eegFileReadOK
                app.notchFltrsHdl = notchFiltersSetup(app.eegSR, app.availableMtgLabels, app.notchSettings, app);
            else
                app.noEEGAlarm.Visible = 'on';
                pause(1);
                app.noEEGAlarm.Visible = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.settingsUI = uifigure('Visible', 'off');
            app.settingsUI.Name = 'elpi Settings';
            app.settingsUI.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.settingsUI.CloseRequestFcn = createCallbackFcn(app, @settingsUICloseRequest, true);
            app.settingsUI.WindowButtonDownFcn = createCallbackFcn(app, @settingsUIWindowButtonDown, true);
            app.FileMenu = uimenu(app.settingsUI);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.AnnotationsMenu = uimenu(app.settingsUI);
            app.AnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotationsMenuSelected, true);
            app.AnnotationsMenu.Text = 'Annotations';
            app.ProfilesMenu = uimenu(app.settingsUI);
            app.ProfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfilesMenuSelected, true);
            app.ProfilesMenu.Text = 'Profiles';
            app.helloUserTxt = uilabel(app.settingsUI);
            app.helloUserTxt.Visible = 'off';
            app.helloUserTxt.Text = 'Hello DLP!';
            app.ChannelsSettingsPanel = uipanel(app.settingsUI);
            app.ChannelsSettingsPanel.Title = 'Channels Settings';
            app.selChannsTree = uitree(app.ChannelsSettingsPanel, 'checkbox');
            app.allNode = uitreenode(app.selChannsTree);
            app.allNode.Text = 'All';
            % Assign Checked Nodes
            app.selChannsTree.CheckedNodes = [app.allNode];
            % Assign Checked Nodes
            app.selChannsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @selChannsTreeCheckedNodesChanged, true);
            app.selectChannelsLabel = uilabel(app.ChannelsSettingsPanel);
            app.selectChannelsLabel.Text = 'Select Channels';
            app.EEGTypeButtonGroup = uibuttongroup(app.ChannelsSettingsPanel);
            app.EEGTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EEGTypeButtonGroupSelectionChanged, true);
            app.EEGTypeButtonGroup.Title = 'EEG Type';
            app.scalpButton = uiradiobutton(app.EEGTypeButtonGroup);
            app.scalpButton.Text = 'Scalp';
            app.scalpButton.Value = true;
            app.intracranialButton = uiradiobutton(app.EEGTypeButtonGroup);
            app.intracranialButton.Text = 'Intracranial';
            app.selectMontageDropDown = uidropdown(app.ChannelsSettingsPanel);
            app.selectMontageDropDown.Items = {'Longitudinal Bipolar', 'Transverse Bipolar'};
            app.selectMontageDropDown.ValueChangedFcn = createCallbackFcn(app, @selectMontageDropDownValueChanged, true);
            app.selectMontageDropDown.Value = 'Longitudinal Bipolar';
            app.SelectScalpEEGMontageLabel = uilabel(app.ChannelsSettingsPanel);
            app.SelectScalpEEGMontageLabel.Text = 'Select Montage';
            app.EEGTimeselectionLabel = uilabel(app.ChannelsSettingsPanel);
            app.EEGTimeselectionLabel.Visible = 'off';
            app.EEGTimeselectionLabel.Text = 'EEG Time selection';
            app.TotalDurationsEditFieldLabel = uilabel(app.ChannelsSettingsPanel);
            app.TotalDurationsEditFieldLabel.Visible = 'off';
            app.TotalDurationsEditFieldLabel.Text = 'Total Duration (s)';
            app.TotalDurationsEditField = uieditfield(app.ChannelsSettingsPanel, 'numeric');
            app.TotalDurationsEditField.Editable = 'off';
            app.TotalDurationsEditField.Enable = 'off';
            app.TotalDurationsEditField.Visible = 'off';
            app.FIRFilterSelectionPanel = uipanel(app.settingsUI);
            app.FIRFilterSelectionPanel.Title = 'FIR Filter Selection';
            app.Bandpass1 = uilabel(app.FIRFilterSelectionPanel);
            app.Bandpass1.Text = 'Bandpass 1';
            app.Bandpass1Low = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass1Low.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass1Low.ValueChangingFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass1Low.Value = '1';
            app.Bandpass1High = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass1High.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass1High.Value = '40';
            app.Bandpass1Order = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass1Order.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass1Order.Value = '512';
            app.Bandpass2 = uilabel(app.FIRFilterSelectionPanel);
            app.Bandpass2.Text = 'Bandpass 2';
            app.Bandpass2Low = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass2Low.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass2Low.Value = '80';
            app.Bandpass2High = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass2High.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass2High.Value = '500';
            app.Bandpass2Order = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass2Order.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass2Order.Value = '512';
            app.Bandpass3 = uilabel(app.FIRFilterSelectionPanel);
            app.Bandpass3.Text = 'Bandpass 3';
            app.Bandpass3Low = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass3Low.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass3Low.Value = '80';
            app.Bandpass3High = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass3High.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass3High.Value = '250';
            app.Bandpass3Order = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass3Order.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass3Order.Value = '512';
            app.Bandpass4 = uilabel(app.FIRFilterSelectionPanel);
            app.Bandpass4.Text = 'Bandpass 4';
            app.Bandpass4Low = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass4Low.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass4Low.Value = '250';
            app.Bandpass4High = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass4High.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass4High.Value = '500';
            app.Bandpass4Order = uieditfield(app.FIRFilterSelectionPanel, 'text');
            app.Bandpass4Order.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Bandpass4Order.Value = '512';
            app.LowLabel = uilabel(app.FIRFilterSelectionPanel);
            app.LowLabel.Text = 'Low ';
            app.HighLabel = uilabel(app.FIRFilterSelectionPanel);
            app.HighLabel.Text = 'High';
            app.OrderLabel = uilabel(app.FIRFilterSelectionPanel);
            app.OrderLabel.Text = 'Order';
            app.ShowcharacteristicsButton = uibutton(app.FIRFilterSelectionPanel, 'state');
            app.ShowcharacteristicsButton.ValueChangedFcn = createCallbackFcn(app, @ShowcharacteristicsButtonValueChanged, true);
            app.ShowcharacteristicsButton.Text = {'Show'; 'characteristics'};
            app.NotchFiltersButton = uibutton(app.FIRFilterSelectionPanel, 'push');
            app.NotchFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFiltersButtonPushed, true);
            app.NotchFiltersButton.Text = {'Notch '; 'Filters'};
            app.DefinedeventstomarkPanel = uipanel(app.settingsUI);
            app.DefinedeventstomarkPanel.Title = 'Defined events to mark';
            app.definedEventsListBox = uilistbox(app.DefinedeventstomarkPanel);
            app.definedEventsListBox.Items = {};
            app.definedEventsListBox.Multiselect = 'on';
            app.definedEventsListBox.Value = {};
            app.startEEGReviewerButton = uibutton(app.settingsUI, 'push');
            app.startEEGReviewerButton.ButtonPushedFcn = createCallbackFcn(app, @startEEGReviewerButtonPushed, true);
            app.startEEGReviewerButton.Text = {'Start'; 'EEG'; 'Reviewer'};
            app.wrongSR_Label = uilabel(app.settingsUI);
            app.wrongSR_Label.Visible = 'off';
            app.wrongSR_Label.Text = {'Sampling Rate'; 'is too low!'};
            app.noEEGAlarm = uilabel(app.settingsUI);
            app.noEEGAlarm.Visible = 'off';
            app.noEEGAlarm.Text = {'Select an '; 'EEG File!'};
            app.WaveletAnalysisPanel = uipanel(app.settingsUI);
            app.WaveletAnalysisPanel.Title = 'Wavelet Analysis';
            app.MaxFreqLabel = uilabel(app.WaveletAnalysisPanel);
            app.MaxFreqLabel.Text = 'Max Freq';
            app.MaxFreqLabel_2 = uilabel(app.WaveletAnalysisPanel);
            app.MaxFreqLabel_2.Text = 'Min Freq';
            app.waveletMinFreqText = uitextarea(app.WaveletAnalysisPanel);
            app.waveletMinFreqText.ValueChangedFcn = createCallbackFcn(app, @waveletMinFreqTextValueChanged, true);
            app.waveletMinFreqText.Value = {'60'};
            app.waveletMaxFreqText = uitextarea(app.WaveletAnalysisPanel);
            app.waveletMaxFreqText.Value = {'500'};
            app.MaxFreqLabel_3 = uilabel(app.WaveletAnalysisPanel);
            app.MaxFreqLabel_3.Text = 'N/A';
            app.waveletStepsText = uitextarea(app.WaveletAnalysisPanel);
            app.waveletStepsText.Editable = 'off';
            app.waveletStepsText.Value = {'0'};
            app.StartsSpinnerLabel = uilabel(app.settingsUI);
            app.StartsSpinnerLabel.Visible = 'off';
            app.StartsSpinnerLabel.Text = 'Start (s)';
            app.reviewStartTimeSpinner = uispinner(app.settingsUI);
            app.reviewStartTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @reviewStartTimeSpinnerValueChanged, true);
            app.reviewStartTimeSpinner.Visible = 'off';
            app.EndsSpinnerLabel = uilabel(app.settingsUI);
            app.EndsSpinnerLabel.Visible = 'off';
            app.EndsSpinnerLabel.Text = 'End (s)';
            app.reviewEndTimeSpinner = uispinner(app.settingsUI);
            app.reviewEndTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @reviewEndTimeSpinnerValueChanged, true);
            app.reviewEndTimeSpinner.Visible = 'off';
            app.reviewEndTimeSpinner.Value = 60;
            % Show the figure after all components are created
            app.settingsUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initAnnotateHFO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.settingsUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.settingsUI)
        end
    end
end