classdef multiEventDeletion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        confirmDeletionButton  matlab.ui.control.Button
        removeButton           matlab.ui.control.Button
        titleLabel             matlab.ui.control.Label
        deleteDataTable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        callingAppHdl =[];
        selEventIdxs = [];
        marksToDelete = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, marksToDelete)
            app.callingAppHdl = callerApp;
            app.marksToDelete = marksToDelete;
            app.deleteDataTable.Data = cat(1, marksToDelete(:,1:4));
            app.deleteDataTable.ColumnFormat = {'char', 'char', 'numeric', 'numeric'};
        end

        % Cell selection callback: deleteDataTable
        function deleteDataTableCellSelection(app, event)
            indices = event.Indices;
            app.selEventIdxs = indices(1);
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            app.marksToDelete(app.selEventIdxs,:) = [];
            app.deleteDataTable.Data = cat(1, app.marksToDelete(:,1:4));
        end

        % Button pushed function: confirmDeletionButton
        function confirmDeletionButtonPushed(app, event)
            app.callingAppHdl.deleteMultiEvent(app.marksToDelete);
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.deleteDataTable = uitable(app.UIFigure);
            app.deleteDataTable.ColumnName = {'Channel'; 'Type'; 'Start (s)'; 'End (s)'};
            app.deleteDataTable.RowName = {};
            app.deleteDataTable.ColumnSortable = true;
            app.deleteDataTable.CellSelectionCallback = createCallbackFcn(app, @deleteDataTableCellSelection, true);
            app.titleLabel = uilabel(app.UIFigure);
            app.titleLabel.Text = 'The following events will be deleted';
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = {'Take out'; 'from list'};
            app.confirmDeletionButton = uibutton(app.UIFigure, 'push');
            app.confirmDeletionButton.ButtonPushedFcn = createCallbackFcn(app, @confirmDeletionButtonPushed, true);
            app.confirmDeletionButton.Text = {'Confirm'; 'Deletion'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiEventDeletion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end