classdef noiseMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoiseMapFigure  matlab.ui.Figure
        noiseSlider     matlab.ui.control.Slider
        SliderLabel     matlab.ui.control.Label
        NoiseMapAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timeVec = []; % Description
        callerHdl = [];
    end
    
    methods (Access = private)
        
        function results = getClickInMap(app)
            mousePos = get(0, 'PointerLocation');
        end
    end
    
    methods (Access = public)
        
        function getClickInMap_Callback(app, source, eventdata)
            mousePos = get(0, 'PointerLocation');
            wWdwPercentage = (mousePos(1)/app.NoiseMapAxes.Position(3));
            idx = round(wWdwPercentage *length(app.timeVec));
            idx = floor(eventdata.IntersectionPoint(1))+1;
            if idx <1
                idx = 1;
            end
            clickTime = app.timeVec(idx);%aap.timeVec(idx);
            stop = 1;

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);    
            screenHeight = screenSize(4);
            
            left = screenWidth*0;
            bottom = screenHeight*0;
            width = screenWidth*0.95;
            height = screenHeight*0.15;
            app.NoiseMapFigure.Position = [left bottom width height];

            left = screenWidth*0.05;
            bottom = screenHeight*0.1;
            width = screenWidth*0.85;
            height = screenHeight*0.05;
            app.noiseSlider.Position = [left bottom width height];

            left = screenWidth*0.05;
            bottom = screenHeight*0;
            width = screenWidth*0.85;
            height = screenHeight*0.1;
            app.NoiseMapAxes.Position = [left bottom width height];

            app.callerHdl.noiseMapClickSynch(clickTime)
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, timeVec, r2Vec)

            app.timeVec = timeVec;
            app.callerHdl  = caller;
            clims = [0 1];
            noiseMapPlot = imagesc(app.NoiseMapAxes, r2Vec, clims);
            colormap(app.NoiseMapAxes, flipud(gray));
            colorbar(app.NoiseMapAxes);
            xlim(app.NoiseMapAxes, [1 length(timeVec)]);
            yticks(app.NoiseMapAxes, []);
            xticks(app.NoiseMapAxes, []);
            ylabel(app.NoiseMapAxes, '');
            xlabel(app.NoiseMapAxes, '');

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);    
            screenHeight = screenSize(4);
            
            left = screenWidth*0;
            bottom = screenHeight*0;
            width = screenWidth;
            height = screenHeight*0.15;
            app.NoiseMapFigure.Position = [left bottom width height];
            app.NoiseMapAxes.Position = [left bottom width height];
            app.noiseSlider.Position = [left bottom width height];

            set(noiseMapPlot,'ButtonDownFcn',{@app.getClickInMap_Callback});

        end

        % Close request function: NoiseMapFigure
        function NoiseMapFigureCloseRequest(app, event)
            delete(app)
        end

        % Window button down function: NoiseMapFigure
        function NoiseMapFigureWindowButtonDown(app, event)
            % mousePos = get(0, 'PointerLocation');
            % wWdwPercentage = (mousePos(1)/app.NoiseMapAxes.Position(3));
            % idx = round(wWdwPercentage *length(app.timeVec));
            % clickTime = app.timeVec(idx);
            % app.callerHdl.noiseMapClickSynch(clickTime)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NoiseMapFigure = uifigure('Visible', 'off');
            app.NoiseMapFigure.Name = 'MATLAB App';
            app.NoiseMapFigure.CloseRequestFcn = createCallbackFcn(app, @NoiseMapFigureCloseRequest, true);
            app.NoiseMapFigure.WindowButtonDownFcn = createCallbackFcn(app, @NoiseMapFigureWindowButtonDown, true);
            app.NoiseMapAxes = uiaxes(app.NoiseMapFigure);
            title(app.NoiseMapAxes, 'Noise Map')
            xlabel(app.NoiseMapAxes, 'X')
            ylabel(app.NoiseMapAxes, 'Y')
            zlabel(app.NoiseMapAxes, 'Z')
            app.SliderLabel = uilabel(app.NoiseMapFigure);
            app.SliderLabel.Text = 'Slider';
            app.noiseSlider = uislider(app.NoiseMapFigure);
            % Show the figure after all components are created
            app.NoiseMapFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noiseMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoiseMapFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoiseMapFigure)
        end
    end
end