classdef noiseMapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NoiseMapFigure           matlab.ui.Figure
        niThSpinner              matlab.ui.control.Spinner
        NIThresholdSpinnerLabel  matlab.ui.control.Label
        timeStr                  matlab.ui.control.Label
        chSpecNI_Axes            matlab.ui.control.UIAxes
        NoiseMapAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs = 0;
        bigWdwLoc = 0;
        bigWdwLenS = 0;
        timeVec = []; % Description
        chspec_ni_vec = [];
        mtgLabels = [];
        niTh = 0.1;
        callerHdl = [];
        crossAvgHdl = [];
        crossSpecHdl = [];
    end
    
    methods (Access = private)
        function getClickInMap_Callback(app, source, eventdata)
            %mousePos = get(0, 'PointerLocation');
            %wWdwPercentage = (mousePos(1)/app.NoiseMapAxes.Position(3));
            %idx = round(wWdwPercentage *length(app.timeVec));
            idx = floor(eventdata.IntersectionPoint(1))+1;
            if idx <1
                idx = 1;
            end
            if idx > length(app.timeVec)
                idx = length(app.timeVec);
            end

            clickTime = app.timeVec(idx);%aap.timeVec(idx);
            app.callerHdl.noiseMapClickSynch(clickTime);
            app.timeStr.Text = strcat("Position: ", num2str(app.timeVec(idx), '%.2f') ," (s)");

        end

        function colorChannLabels(app)
            %% Color channels above ni threshold
            bigWdwLocS = round(app.bigWdwLoc/app.fs/10)*10;
            niSel = (app.timeVec >= bigWdwLocS) & (app.timeVec < bigWdwLocS + app.bigWdwLenS);
            avg_chspec_ni_vec_sel = mean(app.chspec_ni_vec (:,niSel), 2);
            mtgLabelsNI = strcat("(", num2str(avg_chspec_ni_vec_sel, '%.2f'), ") " , app.mtgLabels);
            for ti = 1:size(app.chSpecNI_Axes.YTickLabel,1)
                app.chSpecNI_Axes.YTickLabel{ti} = ['\color{black}' mtgLabelsNI{ti}];
                if avg_chspec_ni_vec_sel(ti) > app.niTh
                    app.chSpecNI_Axes.YTickLabel{ti} = ['\color{red}' mtgLabelsNI{ti}];
                end
            end

        end

    end
    
    methods (Access = public)
        
        function getNewCrossPosition(app, bigWdwLoc, bigWdwLenS)
            app.bigWdwLoc = bigWdwLoc;
            app.bigWdwLenS = bigWdwLenS;

            bigWdwLocS = round(app.bigWdwLoc/app.fs/10)*10;
            crossTimeVecStart = app.timeVec(find(app.timeVec >= bigWdwLocS, 1, 'first'));
            if isempty(crossTimeVecStart)
                return;
            end
            niSel = (app.timeVec >= bigWdwLocS) & (app.timeVec <= crossTimeVecStart+app.bigWdwLenS);
            crosshairsPos = [find(niSel, 1, 'first')-1, find(niSel, 1, 'last')-1];

            if crosshairsPos(1) < 1
                crosshairsPos(1) = 1;
            end

            app.crossAvgHdl{1}.XData = [crosshairsPos(1), crosshairsPos(1)];
            app.crossAvgHdl{2}.XData = [crosshairsPos(2), crosshairsPos(2)];

            app.crossSpecHdl{1}.XData = [crosshairsPos(1), crosshairsPos(1)];
            app.crossSpecHdl{2}.XData = [crosshairsPos(2), crosshairsPos(2)];

            app.timeStr.Text = strcat("Position: ", num2str(app.timeVec(crosshairsPos(1)+1), '%.2f') ," (s)");

            %% Color channels above ni threshold
            app.colorChannLabels();

        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, fs, bigWdwLoc, bigWdwLenS, timeVec, chspec_ni_vec, chavg_ni_vec, mtgLabels)

            app.fs = fs;
            app.bigWdwLoc = bigWdwLoc;
            app.bigWdwLenS = bigWdwLenS;
            bigWdwLocS = round(app.bigWdwLoc/app.fs/10)*10;
            niTimeSel = (timeVec >= bigWdwLocS) & (timeVec <= bigWdwLocS + bigWdwLenS);
            if sum(niTimeSel) == 0
                niTimeSel(end) = true();
            end
            crosshairsPos = [find(niTimeSel, 1, 'first')-1, find(niTimeSel, 1, 'last')-1];

            nrMtgs = size(chspec_ni_vec,1);
            app.timeVec = timeVec;
            app.chspec_ni_vec = chspec_ni_vec;
            app.mtgLabels = mtgLabels;
            app.callerHdl  = caller;

            % NI Threshold
            app.niTh = 0.1;
            app.niThSpinner.Value = app.niTh;

            
            % Channel Average NI
            clims = [0 1];
            chAvgNoiseMapPlot = imagesc(app.NoiseMapAxes, chavg_ni_vec, clims);
            chAvgNoiseMapPlot.XData = 0.5;
            colormap(app.NoiseMapAxes, flipud(gray));
            colorbar(app.NoiseMapAxes);
            xlim(app.NoiseMapAxes, [0 length(timeVec)]);
            xticks(app.NoiseMapAxes, timeVec);
            yticks(app.NoiseMapAxes, 1);
            ylabel(app.NoiseMapAxes, '');
            xlabel(app.NoiseMapAxes, '');
            xticklabels(app.NoiseMapAxes, []);
            yticklabels(app.NoiseMapAxes, 'Avg.NI');
            title(app.NoiseMapAxes, {'Avg. Spectrum'; 'NI'})
            hold(app.NoiseMapAxes, 'on');
            h = colorbar(app.NoiseMapAxes);
            h.Label.String = "Noise Index(0-1)";
            h.Label.Rotation = 270;
            h.Label.VerticalAlignment = "bottom";
            % Crosshairs
            cA1 = plot(app.NoiseMapAxes, [crosshairsPos(1), crosshairsPos(1)], [0.5 1.5], '-', 'Color', [0 1 0 0.8], 'LineWidth', 1);
            cA2 = plot(app.NoiseMapAxes, [crosshairsPos(2), crosshairsPos(2)], [0.5 1.5], '-', 'Color', [0 1 0 0.8], 'LineWidth', 1);
            app.crossAvgHdl = {cA1, cA2};

            %% Channel Specific NI
            clims = [0 1];
            niSel = (timeVec >= bigWdwLocS) & (timeVec < bigWdwLocS + bigWdwLenS);
            avg_chspec_ni_vec_sel = mean(chspec_ni_vec(:,niSel), 2);
            mtgLabelsNI = strcat("(", num2str(avg_chspec_ni_vec_sel, '%.2f'), ") " ,mtgLabels);
            chSpecNoiseMapPlot = imagesc(app.chSpecNI_Axes, chspec_ni_vec, clims);
            chSpecNoiseMapPlot.XData = 0.5;
            colormap(app.chSpecNI_Axes, flipud(gray));
            xticks(app.chSpecNI_Axes, 1:length(timeVec));
            yticks(app.chSpecNI_Axes, 1:nrMtgs);
            xlim(app.chSpecNI_Axes, [0 length(timeVec)]);
            ylim(app.chSpecNI_Axes, [0.5 nrMtgs+0.5]);
            xticklabels(app.chSpecNI_Axes, '');
            yticklabels(app.chSpecNI_Axes, mtgLabelsNI);
            ylabel(app.chSpecNI_Axes, '');
            xlabel(app.chSpecNI_Axes, 'Time (s)');    
            title(app.chSpecNI_Axes, {'Channel Specific Spectrum'; 'NI'})
            hold(app.chSpecNI_Axes, 'on');
            % Colorbar
            h = colorbar(app.chSpecNI_Axes);
            h.Label.String = "Noise Index(0-1)";
            h.Label.Rotation = 270;
            h.Label.VerticalAlignment = "bottom";
            % Grid
            gridX = repmat([1 length(timeVec)], nrMtgs+1,1)';
            gridY = [0.5:1:nrMtgs+0.5; 0.5:1:nrMtgs+0.5]; 
            plot(app.chSpecNI_Axes, gridX, gridY, '-w');
            % Crosshairs
            cB1 = plot(app.chSpecNI_Axes, [crosshairsPos(1), crosshairsPos(1)], [0.5, nrMtgs+0.5], '-', 'Color', [0 1 0 0.8], 'LineWidth', 1);
            cB2 = plot(app.chSpecNI_Axes, [crosshairsPos(2), crosshairsPos(2)], [0.5, nrMtgs+0.5], '-', 'Color', [0 1 0 0.8], 'LineWidth', 1);
            app.crossSpecHdl = {cB1, cB2};

            %% Color channels above ni threshold
            for ti = 1:size(app.chSpecNI_Axes.YTickLabel,1)
                if avg_chspec_ni_vec_sel(ti) > app.niTh
                    app.chSpecNI_Axes.YTickLabel{ti} = ['\color{red}' app.chSpecNI_Axes.YTickLabel{ti}];
                end
            end

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);    
            screenHeight = screenSize(4);
            
            app.NoiseMapFigure.Position = [0 0 screenWidth screenHeight];

            % Avg NI position
            left = screenWidth * 0.02;
            width = screenWidth * 0.92;
            bottom = screenHeight*0.8;
            height = screenHeight*0.2;
            app.NoiseMapAxes.Position = [left bottom width height];

            % Channel Specific NI position
            left = screenWidth * 0.02;
            width = screenWidth * 0.92;
            bottom = screenHeight*0.05;
            height = screenHeight*0.7;
            app.chSpecNI_Axes.Position = [left bottom width height];

            % Current Time String
            left = screenWidth * 0.90;
            width = screenWidth * 0.1;
            bottom = screenHeight*0.70;
            height = screenHeight*0.1;
            app.timeStr.Position = [left bottom width height];
            app.timeStr.Text = strcat("Position: ", num2str(app.timeVec(crosshairsPos(1)+1), '%.2f') ," (s)");

            % NIThresholdSpinner
            left = screenWidth * 0.0;
            width = screenWidth * 0.1;
            bottom = screenHeight*0.70;
            height = screenHeight*0.1;
            app.NIThresholdSpinnerLabel.Position  = [left bottom width height];
            
            left = screenWidth * 0.11;
            width = screenWidth * 0.035;
            bottom = screenHeight*0.74;
            height = screenHeight*0.02;
            app.niThSpinner.Position = [left bottom width height];

            set(chAvgNoiseMapPlot,'ButtonDownFcn',{@app.getClickInMap_Callback});
            set(chSpecNoiseMapPlot,'ButtonDownFcn',{@app.getClickInMap_Callback});

        end

        % Close request function: NoiseMapFigure
        function NoiseMapFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function: NoiseMapAxes, NoiseMapFigure, chSpecNI_Axes
        function NoiseMapFigureKeyPress(app, event)
            key = event.Key;
            winLen = round(app.bigWdwLenS* app.fs);
            if isequal(key, 'rightarrow')
                app.bigWdwLoc = int64(app.bigWdwLoc + winLen);
                
                bigWdwLocS = app.bigWdwLoc/app.fs;
                niTimeSel = (app.timeVec >= bigWdwLocS) & (app.timeVec <= bigWdwLocS + app.bigWdwLenS);
                if sum(niTimeSel) == 0
                    niTimeSel(end) = true();
                end
                newBigWdwLocS = app.timeVec(find(niTimeSel, 1, 'first'));

                app.callerHdl.noiseMapScrollSynch(newBigWdwLocS*app.fs);
            elseif isequal(key, 'leftarrow')
                app.bigWdwLoc = int64(app.bigWdwLoc - winLen);
                if app.bigWdwLoc < 1
                    app.bigWdwLoc = int64(1);
                end

                bigWdwLocS = app.bigWdwLoc/app.fs;
                niTimeSel = (app.timeVec >= bigWdwLocS) & (app.timeVec <= bigWdwLocS + app.bigWdwLenS);
                if sum(niTimeSel) == 0
                    niTimeSel(end) = true();
                end
                newBigWdwLocS = app.timeVec(find(niTimeSel, 1, 'first'));

                app.callerHdl.noiseMapScrollSynch(newBigWdwLocS*app.fs);
            end
            
        end

        % Value changed function: niThSpinner
        function niThSpinnerValueChanged(app, event)
            app.niTh = app.niThSpinner.Value;
            %% Color channels above ni threshold
            app.colorChannLabels();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NoiseMapFigure = uifigure('Visible', 'off');
            app.NoiseMapFigure.Name = 'MATLAB App';
            app.NoiseMapFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.NoiseMapFigure.CloseRequestFcn = createCallbackFcn(app, @NoiseMapFigureCloseRequest, true);
            app.NoiseMapFigure.KeyPressFcn = createCallbackFcn(app, @NoiseMapFigureKeyPress, true);
            app.NoiseMapAxes = uiaxes(app.NoiseMapFigure);
            title(app.NoiseMapAxes, 'Noise Map')
            xlabel(app.NoiseMapAxes, 'X')
            ylabel(app.NoiseMapAxes, 'Y')
            zlabel(app.NoiseMapAxes, 'Z')
            app.NoiseMapAxes.ButtonDownFcn = createCallbackFcn(app, @NoiseMapFigureKeyPress, true);
            app.chSpecNI_Axes = uiaxes(app.NoiseMapFigure);
            title(app.chSpecNI_Axes, 'Title')
            xlabel(app.chSpecNI_Axes, 'X')
            ylabel(app.chSpecNI_Axes, 'Y')
            zlabel(app.chSpecNI_Axes, 'Z')
            app.chSpecNI_Axes.ButtonDownFcn = createCallbackFcn(app, @NoiseMapFigureKeyPress, true);
            app.timeStr = uilabel(app.NoiseMapFigure);
            app.timeStr.Text = 'Position: (s)';
            app.NIThresholdSpinnerLabel = uilabel(app.NoiseMapFigure);
            app.NIThresholdSpinnerLabel.Text = 'NI Threshold';
            app.niThSpinner = uispinner(app.NoiseMapFigure);
            app.niThSpinner.Step = 0.01;
            app.niThSpinner.Limits = [0 1];
            app.niThSpinner.ValueDisplayFormat = '%11.2g';
            app.niThSpinner.ValueChangedFcn = createCallbackFcn(app, @niThSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.NoiseMapFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noiseMapApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NoiseMapFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NoiseMapFigure)
        end
    end
end