classdef notchFiltersSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotchFilters               matlab.ui.Figure
        FilterOrderEditField       matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel  matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        NotchFiltersSetupLabel     matlab.ui.control.Label
        selChannelsTree            matlab.ui.container.CheckBoxTree
        selChannelsNode            matlab.ui.container.TreeNode
        Node2_2                    matlab.ui.container.TreeNode
        Node3_2                    matlab.ui.container.TreeNode
        Node4_2                    matlab.ui.container.TreeNode
        notchFrequenciesTree       matlab.ui.container.CheckBoxTree
        notchFrequenciesNode       matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        EuropeanZoneCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callerApp = [];
        fs = 0;
        availableMtgLabels = {};

        fltrOrder = 1024;
        selFreqsVec = [];
        selMtgLabels = {};
        europe = false();
    end
    
    methods (Access = private)
        
        
        function updateNotchFrequencies(app)
            noiseFreqs = 60*[1:20]';
            if app.europe
                noiseFreqs = 50*[1:20]';
            end
            noiseFreqs(noiseFreqs >= app.fs/2) = [];
            % Populate list of stop frequencies
            a = app.notchFrequenciesNode.Children;
            a.delete;
            app.notchFrequenciesTree.CheckedNodes = [];
            for fi = 1:size(noiseFreqs,1)
                newNode = uitreenode(app.notchFrequenciesNode);
                newNode.Text = num2str(noiseFreqs(fi));
                if sum(ismember(app.selFreqsVec, noiseFreqs(fi))) > 0
                    app.notchFrequenciesTree.CheckedNodes = [app.notchFrequenciesTree.CheckedNodes; newNode];
                end
            end
            checkDel = true(size(app.notchFrequenciesTree.CheckedNodes,1),1);
            for cni = 1:size(app.notchFrequenciesTree.CheckedNodes,1)
                checkVal = str2double(app.notchFrequenciesTree.CheckedNodes(cni).Text);
                if sum(ismember(app.selFreqsVec, checkVal)) > 0
                    checkDel(cni) = false();
                end
            end
            app.notchFrequenciesTree.CheckedNodes(checkDel) = [];
        end

        function updateSelChannels(app)
            % Populate list of selected channels
            a = app.selChannelsNode.Children;
            a.delete;
            app.selChannelsTree.CheckedNodes = [];
            for mi = 1:size(app.availableMtgLabels,1)
                newNode = uitreenode(app.selChannelsNode);
                newNode.Text = app.availableMtgLabels{mi};
                app.selChannelsTree.CheckedNodes = [app.selChannelsTree.CheckedNodes; newNode];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fs, availableMtgLabels, notchSettings, callerApp)

            app.fs = fs;
            app.availableMtgLabels = availableMtgLabels;
            app.callerApp = callerApp;

            if ~isempty(notchSettings.freqs)
                app.fltrOrder = notchSettings.order;
                app.selFreqsVec = notchSettings.freqs;
                app.selMtgLabels = notchSettings.channs;
                app.europe = notchSettings.europe;
            else
                app.fltrOrder = 1024;
                app.selFreqsVec = [];
                app.selMtgLabels = [];
                app.europe = false();
            end
            app.EuropeanZoneCheckBox.Value = app.europe;
            app.notchFrequenciesTree.CheckedNodes = [];
            app.selChannelsTree.CheckedNodes = [];

            app.updateNotchFrequencies();
            app.updateSelChannels();
            app.FilterOrderEditField.Value = app.fltrOrder;
        end

        % Value changed function: EuropeanZoneCheckBox
        function EuropeanZoneCheckBoxValueChanged(app, event)
            app.europe = app.EuropeanZoneCheckBox.Value;
            app.updateNotchFrequencies();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.selFreqsVec = [];
            for mi = 1:size(app.notchFrequenciesTree.CheckedNodes,1)
                selFreqStr = app.notchFrequenciesTree.CheckedNodes(mi).Text;
                if strcmp(selFreqStr, 'All Frequencies')
                    continue;
                end
                selFreqVal = str2double(selFreqStr);
                app.selFreqsVec = cat(1, app.selFreqsVec, selFreqVal);
            end

            app.selMtgLabels = {};
            for mi = 1:size(app.selChannelsTree.CheckedNodes,1)
                selChLabel = app.selChannelsTree.CheckedNodes(mi).Text;
                if strcmp(selChLabel, 'All Channels')
                    continue;
                end
                app.selMtgLabels = cat(1, app.selMtgLabels, selChLabel);
            end
            app.selMtgLabels = sort(app.selMtgLabels);

            notchSettings.order = app.fltrOrder;
            notchSettings.freqs = app.selFreqsVec;
            notchSettings.channs = app.selMtgLabels;
            notchSettings.europe = app.europe;


            uiprogressdlg(app.NotchFilters,'Title','Please Wait',...
            'Message','Filtering in progress', 'Indeterminate','on');
            pause(.5)

            % set(findobj(app.NotchFilters,'Type','uicheckbox'),'Enable','off')
            % set(findobj(app.NotchFilters,'Type','uitree'),'Enable','off')
            % set(findobj(app.NotchFilters,'Type','uilabel'),'Enable','off')
            % set(findobj(app.NotchFilters,'Type','uibutton'),'Enable','off')
            % set(findobj(app.NotchFilters,'Type','uieditfield'),'Enable','off')

            app.callerApp.notchSetupOK(notchSettings);
            app.delete();
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            if value >0 && value <= 1024
                app.fltrOrder = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NotchFilters = uifigure('Visible', 'off');
            app.NotchFilters.Name = 'Notch Filters App';
            app.NotchFilters.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.EuropeanZoneCheckBox = uicheckbox(app.NotchFilters);
            app.EuropeanZoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @EuropeanZoneCheckBoxValueChanged, true);
            app.EuropeanZoneCheckBox.Text = 'European Zone';
            app.notchFrequenciesTree = uitree(app.NotchFilters, 'checkbox');
            app.notchFrequenciesNode = uitreenode(app.notchFrequenciesTree);
            app.notchFrequenciesNode.Text = 'All Frequencies';
            app.Node2 = uitreenode(app.notchFrequenciesNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.notchFrequenciesNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.notchFrequenciesNode);
            app.Node4.Text = 'Node4';
            % Assign Checked Nodes
            app.notchFrequenciesTree.CheckedNodes = [app.Node2];
            app.selChannelsTree = uitree(app.NotchFilters, 'checkbox');
            app.selChannelsNode = uitreenode(app.selChannelsTree);
            app.selChannelsNode.Text = 'All Channels';
            app.Node2_2 = uitreenode(app.selChannelsNode);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.selChannelsNode);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.selChannelsNode);
            app.Node4_2.Text = 'Node4';
            app.NotchFiltersSetupLabel = uilabel(app.NotchFilters);
            app.NotchFiltersSetupLabel.Text = 'Notch Filters Setup';
            app.ApplyButton = uibutton(app.NotchFilters, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.FilterOrderEditFieldLabel = uilabel(app.NotchFilters);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.NotchFilters, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 1024;
            % Show the figure after all components are created
            app.NotchFilters.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = notchFiltersSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotchFilters)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotchFilters)
        end
    end
end