classdef occRateReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UITableCount  matlab.ui.control.Table
        OcurrenceRatesperMinutexMinutesLabel  matlab.ui.control.Label
        UITable       matlab.ui.control.Table
    end

    
    properties (Access = private)
        channelLabels = [];
        eventTypes = [];
    end
    
    methods (Access = private)
        
        function initOccRateReport(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, channelLabels, marksList, durationM)

            app.OcurrenceRatesperMinutexMinutesLabel.Text = {'Ocurrence Rates per Minute'; convertStringsToChars(strcat("(", num2str(durationM), " Minutes)"))};

            app.eventTypes = unique(marksList(:,2))';
            nrChanns = size(channelLabels,1);
            nrTypes = size(app.eventTypes,2);
            occRateCell = repmat({0},nrChanns,nrTypes);
            countCell = repmat({0},1,nrTypes);

            for chi = 1:nrChanns
                channName = channelLabels{chi};
                chSel = strcmp(marksList(:,1), channName);
                for ti = 1:nrTypes
                    typeStr = app.eventTypes{ti};
                    typeSel = strcmp(marksList(:,2), typeStr);
                    occRateVal = sum(chSel&typeSel)/durationM;
                    occRateCell{chi,ti} = occRateVal;
                end
            end
            for ti = 1:nrTypes
                totalCntVal = sum(cell2mat(occRateCell(:,ti))*durationM,1);
                countCell{1,ti} = totalCntVal;
            end


            columnNamesCell = {};
            channLabelsCell = {};
            columnNamesCell = cat(2, columnNamesCell, 'Channel', app.eventTypes);
            channLabelsCell = cat(1, channLabelsCell, '', channelLabels);
            occRatesCell = cat(2, channLabelsCell, occRateCell);
            app.UITable.ColumnName = columnNamesCell;
            app.UITable.Data = occRatesCell;

            countCell = cat(2, {'Total Count'}, countCell);
            app.UITableCount.ColumnName = columnNamesCell;
            app.UITableCount.Data = countCell;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Channel'; 'EOI1'; 'EOI2'; 'EOI3'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.OcurrenceRatesperMinutexMinutesLabel = uilabel(app.UIFigure);
            app.OcurrenceRatesperMinutexMinutesLabel.Text = {'Ocurrence Rates per Minute'; '(x Minutes)'};
            app.UITableCount = uitable(app.UIFigure);
            app.UITableCount.ColumnName = {'Channel'; 'EOI1'; 'EOI2'; 'EOI3'};
            app.UITableCount.RowName = {};
            app.UITableCount.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = occRateReport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end