classdef profilesSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        profilesSetupUIFigure     matlab.ui.Figure
        SavecurrentsettingsLabel  matlab.ui.control.Label
        titleText                 matlab.ui.control.Label
        newProfileLabel           matlab.ui.control.Label
        newProfilename            matlab.ui.control.TextArea
        saveButton                matlab.ui.control.Button
        profileExistsLabel        matlab.ui.control.Label
        ExistingProfilesLabel     matlab.ui.control.Label
        existingProfilesList      matlab.ui.control.ListBox
        loadProfileButton         matlab.ui.control.Button
        selectProfileToLoadLabel  matlab.ui.control.Label
        deleteButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        existingProfileNames  = {};
        activeProfile = [];
        callingAppHdl = [];
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, existingProfileNames)
            app.callingAppHdl = callingApp;
            app.existingProfileNames  = existingProfileNames;
            app.existingProfilesList.Items = app.existingProfileNames;
            app.activeProfile = [];

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            app.profilesSetupUIFigure.Position(1) = screenWidth*0.4;
            app.profilesSetupUIFigure.Position(2) = screenHeight*0.2;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if isempty(app.newProfilename.Value{1})
                %% event type or color not defined
                app.profileExistsLabel.Text = {'Not Enough'; 'Profile Info!'};
                app.profileExistsLabel.Visible = 'on'; pause(1);
                app.profileExistsLabel.Visible = 'off';
            else
                %% event type and color are defined
                newProfileName = app.newProfilename.Value{1};
                profileExists = sum(ismember(app.existingProfileNames, newProfileName))>0;
                if isempty(app.existingProfileNames)
                    app.existingProfileNames = {newProfileName};
                    app.existingProfilesList.Items = app.existingProfileNames;
                    app.activeProfile = {newProfileName};
                    app.callingAppHdl.saveUserProfile(app, app.activeProfile);
                elseif not(profileExists)
                    %% event list is empty or new event is not yet in the list (i.e. not repeated)
                    app.existingProfileNames = cat(1, app.existingProfileNames, newProfileName);
                    app.existingProfilesList.Items = app.existingProfileNames;
                    app.activeProfile = {newProfileName};
                    app.callingAppHdl.saveUserProfile(app, app.activeProfile);
                else
                    %% new event is already in the list
                    app.profileExistsLabel.Text = {'Profile '; 'already exists!'};
                    app.profileExistsLabel.Visible = 'on'; pause(1);
                    app.profileExistsLabel.Visible = 'off';
                end
            end
        end

        % Button pushed function: loadProfileButton
        function loadProfileButtonPushed(app, event)
            if not(isempty(app.existingProfilesList.Value))
                app.activeProfile = app.existingProfilesList.Value;
                app.callingAppHdl.loadUserProfile(app, app.activeProfile);
            else
                app.selectProfileToLoadLabel.Visible = 'on';
            end
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            if not(isempty(app.existingProfilesList.Value))
                app.activeProfile = app.existingProfilesList.Value;
                app.callingAppHdl.deleteUserProfile(app, app.activeProfile);
            else
                app.selectProfileToLoadLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.profilesSetupUIFigure = uifigure('Visible', 'off');
            app.profilesSetupUIFigure.Name = 'profilesSetup';
            app.profilesSetupUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.deleteButton = uibutton(app.profilesSetupUIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = {'Delete'; 'Profile'};
            app.selectProfileToLoadLabel = uilabel(app.profilesSetupUIFigure);
            app.selectProfileToLoadLabel.Visible = 'off';
            app.selectProfileToLoadLabel.Text = 'Select a Profile!';
            app.loadProfileButton = uibutton(app.profilesSetupUIFigure, 'push');
            app.loadProfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadProfileButtonPushed, true);
            app.loadProfileButton.Text = {'Load '; 'Profile'};
            app.existingProfilesList = uilistbox(app.profilesSetupUIFigure);
            app.existingProfilesList.Items = {};
            app.existingProfilesList.Value = {};
            app.ExistingProfilesLabel = uilabel(app.profilesSetupUIFigure);
            app.ExistingProfilesLabel.Text = 'Existing Profiles';
            app.profileExistsLabel = uilabel(app.profilesSetupUIFigure);
            app.profileExistsLabel.Visible = 'off';
            app.profileExistsLabel.Text = {'Profile name'; 'already exists!'};
            app.saveButton = uibutton(app.profilesSetupUIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save';
            app.newProfilename = uitextarea(app.profilesSetupUIFigure);
            app.newProfileLabel = uilabel(app.profilesSetupUIFigure);
            app.newProfileLabel.Text = 'New Profile Name';
            app.titleText = uilabel(app.profilesSetupUIFigure);
            app.titleText.Text = 'User Profiles';
            app.SavecurrentsettingsLabel = uilabel(app.profilesSetupUIFigure);
            app.SavecurrentsettingsLabel.Text = 'Save current settings';
            % Show the figure after all components are created
            app.profilesSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = profilesSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.profilesSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.profilesSetupUIFigure)
        end
    end
end