classdef smallPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotateHFO_GUI   matlab.ui.Figure
        smallWdwSlider    matlab.ui.control.Slider
        wdwsLengthLabel   matlab.ui.control.Label
        smallWdwSetLen    matlab.ui.control.NumericEditField
        cwtLockCheck      matlab.ui.control.CheckBox
        refreshHeatmap    matlab.ui.control.Button
        heatmapFltrBttn   matlab.ui.control.Button
        smallWdwFltrBttn  matlab.ui.control.Button
        smallWdw          matlab.ui.control.UIAxes
        heatmap           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        standalone = false();
        settings = []; % Description
        permits = [];
        patName = [];
        fs = [];
        physDim = [];
        nSamples = [];
        nrMtgsAbs = [];
        mtgLabelsAbs = [];
        selMtgsToShowIdxs = [];
        mtgSignalsAbs = [];
        mtgSignalsLowAbs = [];
        mtgSignalsHFOAbs = []; 
        mtgSignalsRippleAbs = [];
        mtgSignalsFRAbs = [];

        nrMtgs = [];
        mtgLabels = [];
        mtgSignals = [];
        mtgSignalsLow = [];
        mtgSignalsHFO = []; 
        mtgSignalsRipple = [];
        mtgSignalsFR = [];
        time = [];
        channSelectorAppHdl = [];
        edfAnnotsFilepath = [];
        chunkSmplSize = [];
        totalNrFileparts = 0;
        filepartSel = 1;


        %% Big Window plotting
        bigWdwLenS = [];
        bigWdwLoc = [];
        yLimsBig = [];
        yIntervBig = [];
        fltrBttnBigIdx = 1;
        selBigSgnlsIdx = [];
        bigSgnlColors = [];
        lw = 0.5;

        %% Small Window plotting
        smallWdwLenS = [];
        smallWdwLoc = [];
        yLimsSmall = [];
        yIntervSmall = 0;
        fltrBttnSmallIdx = 1;
        selSmallSgnlsIdx = 0;
        smallSgnlColors = [];
        smallMtgLabels = [];

        %% Spectrohgram plotting
        spctgrmSigIdx = 0;
        fltrBttnSpctgrmIdx = 1;
        zLimsSpctgrm = [];
        spctgrmScroolLock = false();

        %% Marking
        marksList = {};
        markingEnabled = false();
        markingMode = 0;
        awaitMarkSpecKey = true;
        chSpecMark = true();
        eventEditorHdl = [];
        allChStartY = [];
        allChAvgY = [];
        selChAvgY = [];
        annotTypesAndColorsList = [];
        hfoMarkColor = [0 0.4470 0.7410];
        badsegColor = [1 0 0];
        annotationTypeSelectorHdl = [];
        annotsSetupHdl = [];
        validAnnotTypes = {};
        editedAnnotsFN = {};
        nrAnnotsEdits = 0;
        timer = [];
        lastMarkBig = [];
        lastMarkSmall = [];
        annotsTableHdl = [];
        badSegmentsEditorHdl = [];
        annotsPath = [];
        occRateReportHdl = [];
        badsegAnnotations = {};
        multiEventDeletionHdl = [];
    end
        
    %% Define own method shere
    methods (Access = private)
        
        %% Initialize all variables if settings is called again
        function initializeVariables(app)

            app.settings = []; % Description
            app.permits = [];

            app.patName = [];
            app.fs = [];
            app.physDim = [];
            app.nSamples = [];
            app.nrMtgsAbs = [];
            app.mtgLabelsAbs = [];
            app.selMtgsToShowIdxs = [];
            app.mtgSignalsAbs = [];
            app.mtgSignalsLowAbs = [];
            app.mtgSignalsHFOAbs = []; 
            app.mtgSignalsRippleAbs = [];
            app.mtgSignalsFRAbs = [];
            app.nrMtgs = [];
            app.mtgLabels = [];
            app.mtgSignals = [];
            app.mtgSignalsLow = [];
            app.mtgSignalsHFO = []; 
            app.mtgSignalsRipple = [];
            app.mtgSignalsFR = [];
            app.time = [];
            app.channSelectorAppHdl = [];
            app.edfAnnotsFilepath = [];
            app.chunkSmplSize = [];
            app.totalNrFileparts = 0;
            app.filepartSel = 1;


            %% all Channels plotting
            app.bigWdwLenS = 10;
            app.bigWdwLoc = int64(1);
            app.yLimsBig = [];
            app.yIntervBig = [];
            app.lw = 0.5;
            app.fltrBttnBigIdx = 1;
            app.selBigSgnlsIdx = [];
            app.bigSgnlColors = [];
            app.bigWdw.Color = [0.86275  0.98039  0.86275];

            %% zoomed Channels plotting
            app.smallWdwLenS = 1;
            app.smallWdwLoc = 1;
            app.yLimsSmall = [];
            app.yIntervSmall = 0;
            app.fltrBttnSmallIdx = 1;
            app.selSmallSgnlsIdx = 0;
            app.smallSgnlColors = [];
            app.smallMtgLabels = [];
            app.smallWdw.Color = [0.86275  0.98039  0.86275];

            %% Spectrohgram plotting
            app.spctgrmSigIdx = 0;
            app.fltrBttnSpctgrmIdx = 1;
            app.zLimsSpctgrm = [];
            app.spctgrmScroolLock = app.cwtLockCheck.Value;

            %% Marking
            app.marksList = {};
            app.markingEnabled = false();
            app.markingMode = 0;
            app.awaitMarkSpecKey = true;
            app.chSpecMark = true();
            app.eventEditorHdl = [];
            app.allChStartY = [];
            app.allChAvgY = [];
            app.selChAvgY = [];
            app.annotationTypeSelectorHdl = [];
            app.annotsSetupHdl = [];
            app.validAnnotTypes = {};
            app.editedAnnotsFN = {};
            app.nrAnnotsEdits = 0;
            app.annotTypesAndColorsList = {};
            app.lastMarkBig = 'IED';
            app.lastMarkSmall = 'HFO';
            app.annotsTableHdl = [];
            app.annotsPath = [];
            app.occRateReportHdl = [];

        end

        %% Plot signals selected on the left
        function plotSignalsSmall(app)
            if any(app.selBigSgnlsIdx)
                nrSelChs = sum(app.selBigSgnlsIdx);
                winLen= round(app.smallWdwLenS * app.fs);
                if winLen > app.nSamples
                    winLen= app.nSamples;
                end

                yInterv = app.yIntervSmall;
                yTotal = yInterv * nrSelChs;

                if app.smallWdwLoc+winLen-1  > app.nSamples
                    app.smallWdwLoc = int64(app.nSamples-winLen+1);
                end
                loc = app.smallWdwLoc;

                rowSel = app.selBigSgnlsIdx;
                colSel  = false(1,app.nSamples);
                colSel(loc:(loc+winLen-1)) = true;
                if (loc+winLen-1) > app.nSamples
                    stop = 1;
                end
                dataWin = app.getCurrentFilteredData(rowSel, colSel, app.fltrBttnSmallIdx);
                rawDataWin = app.getCurrentFilteredData(rowSel, colSel, 1);


                % plotfig
                dataWin = bsxfun(@minus,dataWin,dataWin(:,1));
                yStart = (nrSelChs:-1:1)*yInterv-(yInterv/2);
                dataWin = bsxfun(@plus,dataWin,yStart');
                tWin = app.time(loc:(loc+winLen-1));
                app.selChAvgY = mean(dataWin,2);
                
                hold(app.smallWdw, "off");
                colororder(app.smallWdw, app.smallSgnlColors);
                smallHdlr = plot(app.smallWdw, tWin, dataWin, 'LineWidth', app.lw, 'Tag', 'selChs');
                hold(app.smallWdw, "all");

%                 rawDataWin = bsxfun(@minus,rawDataWin,rawDataWin(:,1));
%                 rawDataWin = bsxfun(@plus, rawDataWin, yStart');
%                 colororder(app.smallWdw, repmat([0.8 0.8 0.8], size(rawDataWin,1), 1));
%                 smallHdlr = plot(app.smallWdw, tWin, rawDataWin, 'LineWidth', app.lw, 'Tag', 'selChs');

                if app.spctgrmSigIdx > 0
                    channLabel = app.mtgLabels{app.spctgrmSigIdx};
                    title(app.smallWdw, channLabel);
                end

                % Plot scale
                scaleColor = [0.4940 0.1840 0.5560];
                scaleXPos = [tWin(end-round(1.5*winLen/10)) tWin(end-round(0.5*winLen/10))];
                scaleYPos = [0.5*yInterv 1.5*yInterv];
                plot(app.smallWdw, scaleXPos, [scaleYPos(1) scaleYPos(1)], '-', 'Color', scaleColor, 'LineWidth', 1.5, 'Tag', 'allCh');
                text(app.smallWdw, scaleXPos(1), 0.7*yInterv, strcat(num2str(app.smallWdwLenS/10),' s'), 'Color', scaleColor, 'FontWeight', 'bold', 'BackgroundColor', 'w');
                plot(app.smallWdw, [scaleXPos(2) scaleXPos(2)], scaleYPos, '-', 'Color', scaleColor, 'LineWidth', 1.5, 'Tag', 'allCh');
                text(app.smallWdw, scaleXPos(2) + (0.2)*(scaleXPos(2)-scaleXPos(1)), scaleYPos(1),...
                    strcat(num2str(yInterv)," ", app.physDim), 'Rotation', 90, 'Color', scaleColor,'FontWeight', 'bold', 'BackgroundColor', 'w');

    
                set(smallHdlr, 'ButtonDownFcn', @app.selLineSmall_Callback)
                channLabels = flip(app.smallMtgLabels);
                set(app.smallWdw,'ytick', yStart(end:-1:1), 'yticklabel', channLabels);
                xticks(app.smallWdw, 'auto');
                xticklabels(app.smallWdw, 'auto');
                ylim(app.smallWdw, [0 yTotal]);
                xlim(app.smallWdw, [tWin(1) tWin(end)]);
                set(app.smallWdw, 'XMinorGrid', 'on', 'MinorGridColor', [0 1 0], 'MinorGridAlpha', 1);
                ytickangle(app.smallWdw, 45);

                app.bigWdwLoc = app.smallWdwLoc;
            else
                set(app.smallWdw,'ytick', [], 'yticklabel', []);
                cla(app.smallWdw);
            end
        end

        function plotHeatmap(app)

            if app.spctgrmSigIdx > 0 && app.selSmallSgnlsIdx > 0 && sum(app.smallSgnlColors(:)) > 0

                winLen= round(app.smallWdwLenS * app.fs);
                if winLen > app.nSamples
                    winLen= app.nSamples;
                end

                if app.smallWdwLoc + (winLen - 1) > app.nSamples
                    app.smallWdwLoc = int64(app.nSamples-winLen+1);
                end
                loc = int64(app.smallWdwLoc);
               
                padSec = 2;
                artfctPadSec = 2;
                padWdwTot = round(padSec * app.fs) - winLen;
                paddingPre = 0;
                paddingPost = 0;
                artfctPadPre = 0;
                artfctPadPost = 0;

                if (loc - int64(artfctPadSec * app.fs)) > 0
                    artfctPadPre = int64(artfctPadSec * app.fs);
                end
                if (loc - artfctPadPre - int64(padWdwTot/2)) > 0
                    paddingPre = int64(padWdwTot/2);
                end

                if (loc + (winLen-1) + int64(artfctPadSec * app.fs)) <= app.nSamples
                    artfctPadPost = int64(artfctPadSec * app.fs);
                end
                if (loc + (winLen-1) + artfctPadPost + int64(padWdwTot/2)) <= app.nSamples
                    paddingPost = int64(padWdwTot/2);
                end

                rowSel = false(app.nrMtgs,1);
                rowSel(app.spctgrmSigIdx) = true;
                colSel  = false(1,app.nSamples);
                colSel(loc-artfctPadPre-paddingPre:loc+(winLen-1)+artfctPadPost+paddingPost) = true;
                spctgrmNormDataWin = app.getCurrentFilteredData(rowSel, colSel, app.fltrBttnSpctgrmIdx);

                [cfs,frq, coi] = cwt(spctgrmNormDataWin, 'amor', app.fs, 'FrequencyLimits',[60 500]);
                absCFS = abs(cfs);
                normCFS = (absCFS - mean(absCFS, 'all'))/std(absCFS, 0, 'all');
                normScaledCFS = rescale(normCFS);
                wdwNormScaleCFS = normScaledCFS(:, 1+artfctPadPre+paddingPre:size(normScaledCFS,2)-artfctPadPost-paddingPost);

                %normScaledCFS = (normCFS-min(normCFS(:))) ./ max(normCFS(:)-min(normCFS(:)));
                %normScaledCFS = normScaledCFS(:, 1+normPad:size(cfs,2)-normPad);
                %wdwNormScaleCFS = normScaledCFS(:, 1:winLen);
                
                tWin = app.time(loc:(loc+(winLen-1)));

                ch = contour(app.heatmap, tWin, frq, wdwNormScaleCFS, 'LineStyle','none', 'LineColor',[0 0 0], 'Fill','on', 'Tag', 'heatmap');
                disableDefaultInteractivity(app.heatmap);
                channLabel = app.mtgLabels{app.spctgrmSigIdx};
                title(app.heatmap, channLabel);


                ylabel(app.heatmap, 'Frequency (Hz)')
                set(app.heatmap,'Colormap',jet)
                set(app.heatmap,'XLim',[min(tWin) max(tWin)], 'YLim',[min(frq) max(frq)],'XGrid','On', 'YGrid','On')

                colorbar(app.heatmap, 'east', 'AxisLocation','in', 'Color', 'w', 'FontSize', 12);
                if isempty(app.zLimsSpctgrm)
                        app.zLimsSpctgrm = [min(normScaledCFS(:)) max(normScaledCFS(:))];
                end
                clim(app.heatmap, app.zLimsSpctgrm);

            else
                cla(app.heatmap);
                app.zLimsSpctgrm = [];
            end

        end

        %% Select or deselect signals, Small
        function selLineSmall_Callback(app,  source, eventdata)
            selYLabelIdx = source.SeriesIndex;
            app.highlightClickChLabelSmall(selYLabelIdx);
        end

        function highlightClickChLabelSmall(app, selYLabelIdx)
            if app.selSmallSgnlsIdx == 0 
                app.selSmallSgnlsIdx = selYLabelIdx;
                app.smallSgnlColors(selYLabelIdx,:) = [1 0 0];
                selSignsIdx = find(app.selBigSgnlsIdx);
                app.spctgrmSigIdx = selSignsIdx(selYLabelIdx);
            else
                app.selSmallSgnlsIdx = 0;
                app.smallSgnlColors = repmat([0 0 0], sum(app.selBigSgnlsIdx), 1);
                app.spctgrmSigIdx = 0;
            end
            t = app.spctgrmSigIdx;
            %% slow event
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawMarksSmall();
            app.plotHeatmap();
            app.drawSlowObjects();
        end

        %% Test Input Type
        function result = isAdjustAction(app, event)
            result = isequal(event.Key, 'uparrow') || isequal(event.Key, 'downarrow') ||...
                isequal(event.Key, 'rightarrow') || isequal(event.Key, 'leftarrow');
        end

        %% Mark events
        function updateMarkingStateLamp(app)
            if app.markingEnabled
                app.markModeTxtSmall.Text = {'Marking'; 'On'};
                app.markModeLampSmall.Color = [0 1 0];
                app.awaitMarkSpecKey = true;
            elseif ~app.markingEnabled
                app.markModeTxtSmall.Text = {'Marking'; 'Off'};
                app.markModeLampSmall.Color = [1 1 1];
                app.awaitMarkSpecKey = false;
            end
        end

        function handleKeyRelease(app, event)
            key = event.Key;
            %% Determine which panel to modify
            mousePos = get(0, 'PointerLocation');
            mouseBigWdw = mousePos(1)>=app.bigWdw.Position(1) & mousePos(1)<=app.bigWdw.Position(1)+app.bigWdw.Position(3);
            mouseSmallWdw = mousePos(1)>=app.smallWdw.Position(1) & mousePos(1)<=app.smallWdw.Position(1)+app.smallWdw.Position(3);
            mouseHeatmap = false();
            % mouseHeatmap= mousePos(1)>=app.heatmap.Position(1) & mousePos(1)<=app.heatmap.Position(1)+app.heatmap.Position(3)&...
            %     mousePos(2)>=app.heatmap.Position(2) & mousePos(2)<=app.heatmap.Position(2)+app.heatmap.Position(4);

%             if ~isempty(event.Source.CurrentObject)
%                 %% Synchronize panels when key is released
%                 if isequal(event.Source.CurrentObject.Tag,'allCh') ||...
%                         isequal(event.Source.CurrentObject.Tag,'selChs')
                    if isequal(key, 'rightarrow') || isequal(key, 'leftarrow') ||...
                        isequal(key, 'uparrow') || isequal(key, 'downarrow')
                        %% slow event
                        app.plotSignalsBig();
                        app.plotSignalsSmall();
                        app.drawSlowObjects();
                        app.updateSlideBars();
                        if app.spctgrmScroolLock && not(mouseHeatmap)
                            app.zLimsSpctgrm = [];
                            app.plotHeatmap();
                        end
                    end
                %end
            %end          
        end
        
        function drawMarksBig(app)
 
            if ~isempty(app.marksList)
                [wdwRelevantMarks, relevantMarksIndices] = ...
                    getValidMarksBig(app.marksList, app.bigWdwLoc, app.bigWdwLenS*app.fs, app.mtgLabels , app.validAnnotTypes);

                nrMarks = size(wdwRelevantMarks,1);
                for mi = 1:nrMarks
                    mark = wdwRelevantMarks(mi,:);
                    markIdx = relevantMarksIndices(mi);
                    channLabel = mark{1};
                    markType = mark{2};
                    startT = mark{3};
                    endT = mark{4};
                    durationT = endT - startT;

                    isBadSegMark = strcmp(markType, 'Bad');

                    markChIdx = ismember(app.mtgLabels, channLabel);
                    markChAvg = app.allChAvgY(markChIdx);
                    yVal = markChAvg - app.yIntervBig/4;
                    wVal = app.yIntervBig/2;
                    roiPosition = [startT yVal durationT wVal];

                    channSpecAnnot = mark{8};
%                     channSpecAnnot = true;
%                     if not(strcmp(mark{8},""))
%                         channSpecAnnot = mark{8};
%                     end

                    if not(channSpecAnnot)
                        yVal = app.bigWdw.YLim(1);
                        wVal = app.bigWdw.YLim(2);
                        roiPosition = [startT yVal durationT wVal];
                    end
                    
                    markColor = app.hfoMarkColor;
                    markTypeIdx = strcmp(app.annotTypesAndColorsList(:,1), mark{2});
                    if sum(markTypeIdx) > 0
                        markColor = app.annotTypesAndColorsList{markTypeIdx, 2};
                    end
                    if isBadSegMark
                        markColor = app.badsegColor;
                    end
                    alphaVal = 0.2;

                    rctgHdr = rectangle(app.bigWdw,'Position', roiPosition,...
                    'FaceColor', [markColor alphaVal], 'EdgeColor', [markColor alphaVal]);
                    annotText = markType;
                    posVec = [roiPosition(1)+(roiPosition(3)/1) roiPosition(2)+(1*roiPosition(4))];
                    rotAngle = 60;
                    if contains(lower('Ripple'), lower(markType)) || contains(lower('Rppl'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1*roiPosition(4))];
                    elseif contains(lower('FR'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1.1*roiPosition(4))];
                    elseif contains(lower('IED'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1.2*roiPosition(4))];
                    end

                    if channSpecAnnot
                        markTextHdlT = text(app.bigWdw,'Position', posVec, ...
                        'string', annotText, 'Rotation', rotAngle);
                    else
                        markTextHdlT = text(app.bigWdw,'Position',...
                        [roiPosition(1)+(roiPosition(3)/2) roiPosition(2)+(0.8*roiPosition(4))],...
                        'string', annotText, 'Rotation', rotAngle);
                    end

                    if not(isBadSegMark)
                        set(rctgHdr, 'ButtonDownFcn', {@app.markEditClickBig_Callback, mark, markIdx});
                        set(markTextHdlT, 'ButtonDownFcn', {@app.markEditClickBig_Callback, mark, markIdx});
                    end
                end
            end
        end

        function drawMarksSmall(app)
            if (sum(app.selBigSgnlsIdx) > 0) && ~isempty(app.marksList)
                [wdwRelevantMarks, relevantMarksIndices] = ...
                    getValidMarks(app.marksList, app.smallWdwLoc, app.smallWdwLenS*app.fs, app.smallMtgLabels, app.validAnnotTypes);

                nrMarks = size(wdwRelevantMarks,1);
                for mi = 1:nrMarks
                    mark = wdwRelevantMarks(mi,:);
                    markIdx = relevantMarksIndices(mi);
                    channLabel = mark{1};
                    markType = mark{2};
                    startT = mark{3};
                    endT = mark{4};
                    durationT = endT - startT;

                    isBadSegMark = strcmp(markType, 'Bad');
                    
                    markChIdx = ismember(app.smallMtgLabels, channLabel);
                    markChAvg = app.selChAvgY(markChIdx);
                    yVal = markChAvg - app.yIntervSmall/4;
                    wVal = app.yIntervSmall/2;
                    roiPosition = [startT yVal durationT wVal];
                    
                    markColor = app.hfoMarkColor;
                    markTypeIdx = strcmp(app.annotTypesAndColorsList(:,1), mark{2});
                    if sum(markTypeIdx) > 0
                        markColor = app.annotTypesAndColorsList{markTypeIdx, 2};
                    end
                    if isBadSegMark
                        markColor = app.badsegColor;
                    end

                    alphaVal = 0.2;

                    rctgHdr = rectangle(app.smallWdw,'Position', roiPosition,...
                    'FaceColor', [markColor alphaVal], 'EdgeColor', [markColor alphaVal]);
                    annotText = markType;
                    posVec = [roiPosition(1)+(roiPosition(3)/1) roiPosition(2)+(1*roiPosition(4))];
                    rotAngle = 60;
                    if contains(lower('Ripple'), lower(markType)) || contains(lower('Rppl'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1*roiPosition(4))];
                    elseif contains(lower('FR'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1.1*roiPosition(4))];
                    elseif contains(lower('IED'), lower(markType))
                        rotAngle = 60;
                        posVec = [roiPosition(1), roiPosition(2)+(1.2*roiPosition(4))];
                    end
                    markTextHdlT = text(app.smallWdw,'Position', posVec, ...
                        'string', annotText, 'Rotation', rotAngle);
                    set(rctgHdr, 'ButtonDownFcn', {@app.markEditClickSmall_Callback, mark, markIdx});
                    set(markTextHdlT, 'ButtonDownFcn', {@app.markEditClickSmall_Callback, mark, markIdx});
                end
            end                    
        end

        function drawSlowObjects(app)
            app.plotHeatmap();
            app.drawMarksBig();
            app.drawMarksSmall();
        end

        function updateSlideBars(app)
            sliderVal = double(100*app.bigWdwLoc/app.nSamples);
            app.bigWdwSlider.Value = sliderVal;
            app.smallWdwSlider.Value = sliderVal;
        end

        function handleNewMarkEventBig(app)
            % close event editor if open
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end

            if app.markingEnabled && app.markingMode
                roi = drawrectangle(app.bigWdw);

                %% Determine in which panel the annotation is being made
                mousePos = get(0, 'PointerLocation');
                mouseBigWdw = mousePos(1)>=app.bigWdw.Position(1) & mousePos(1)<=app.bigWdw.Position(1)+app.bigWdw.Position(3);
            
                roiPosition = roi.Position; 
                delete(roi);
                                
                % Find which channel was marked
                markMidPointY = (roiPosition(2) + (roiPosition(2)+roiPosition(4)))/2;
                [~, markChIdx] = min(abs(app.allChAvgY - markMidPointY ));


                % get Mark info
                startTime = roiPosition(1);
                endTime = roiPosition(1) + roiPosition(3);
                durationMs = endTime-startTime;

                if mouseBigWdw && (durationMs > 0.001)

                    startSample = int64(round(startTime*app.fs));
                    endSample = int64(round(endTime*app.fs));
                    tempMarkIdx = size(app.marksList,1)+1;
                    t = datetime;
                    timeStr = strcat(num2str(t.Day), '.', num2str(t.Month), '.',num2str(t.Year));
                    timeStr = strcat(timeStr, '_',num2str(t.Hour), ':',num2str(t.Minute), ':',num2str(round(t.Second)));
    
                    newMark.idx = int64(tempMarkIdx);
                    newMark.channel = app.mtgLabels{markChIdx};
                    newMark.type = app.lastMarkBig;
                    newMark.startTime = double(startTime);
                    newMark.endTime = double(endTime);
                    newMark.startSample = int64(startSample);
                    newMark.endSample = int64(endSample);                
                    newMark.comments = "";
                    newMark.channSpec = app.chSpecMark;
                    newMark.creationTime = timeStr;

                    app.chSpecMark = true;
                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;

                    app.saveMark(newMark, 1);
                else
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                    app.plotSignalsBig();
                    app.plotSignalsSmall();
                    app.drawSlowObjects()
                end
            end
        end

        function handleNewMarkEventSmall(app)
            % close event editor if open
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end

            if app.markingEnabled && app.markingMode
                roi = drawrectangle(app.smallWdw);

                %% Determine in which panel the annotation is being made
                mousePos = get(0, 'PointerLocation');
                mouseSmallWdw = mousePos(1)>=app.smallWdw.Position(1) & mousePos(1)<=app.smallWdw.Position(1)+app.smallWdw.Position(3);

                roiPosition = roi.Position; 
                delete(roi);
                
                % Find which channel was marked
                markMidPointY = (roiPosition(2) + (roiPosition(2)+roiPosition(4)))/2;
                [~, markChIdx] = min(abs(app.selChAvgY - markMidPointY ));

                % get Mark info
                startTime = roiPosition(1);
                endTime = roiPosition(1) + roiPosition(3);
                durationMs = endTime-startTime;

                if mouseSmallWdw && (durationMs > 0.001) 

                    startSample = int64(round(startTime*app.fs));
                    endSample = int64(round(endTime*app.fs));            
                    tempMarkIdx = size(app.marksList,1)+1;
                    t = datetime;
                    timeStr = strcat(num2str(t.Day), '.', num2str(t.Month), '.',num2str(t.Year));
                    timeStr = strcat(timeStr, '_',num2str(t.Hour), ':',num2str(t.Minute), ':',num2str(round(t.Second)));
    
                    newMark.idx = int64(tempMarkIdx);
                    newMark.channel = app.smallMtgLabels{markChIdx};
                    newMark.type = app.lastMarkSmall;
                    newMark.startTime = double(startTime);
                    newMark.endTime = double(endTime);
                    newMark.startSample = int64(startSample);
                    newMark.endSample = int64(endSample);                
                    newMark.comments = "";
                    newMark.channSpec = true();
                    newMark.creationTime = timeStr;

                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;

                    app.saveMark(newMark, 2);    
                else
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                    app.plotSignalsBig();
                    app.plotSignalsSmall();
                    app.drawSlowObjects()
                end
            end
        end

        function handleGroupDeleteBig(app)
            % close event editor if open
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end
            if ~isempty(app.multiEventDeletionHdl) && isvalid(app.multiEventDeletionHdl)
                app.multiEventDeletionHdl.delete;
            end

            if app.markingEnabled && app.markingMode
                app.markModeTxtSmall.Text = {'Multidelete'; ''};
                roi = drawrectangle(app.bigWdw);

                %% Determine in which panel the annotation is being made
                mousePos = get(0, 'PointerLocation');
                mouseBigWdw = mousePos(1)>=app.bigWdw.Position(1) & mousePos(1)<=app.bigWdw.Position(1)+app.bigWdw.Position(3);
            
                roiPosition = roi.Position; 
                delete(roi);
                                
                % Find which channel was marked
                channDelSel= app.allChAvgY >= roiPosition(2) & app.allChAvgY <= roiPosition(2)+roiPosition(4);
                channDelNames = app.mtgLabels(channDelSel);

                % get Mark info
                startTime = roiPosition(1);
                endTime = roiPosition(1) + roiPosition(3);
                durationMs = endTime-startTime;

                if mouseBigWdw && (durationMs > 0.001)

                    marksTimeSel = cell2mat(app.marksList(:,3))>=startTime & cell2mat(app.marksList(:,3))<=endTime;
                    marksChannSel = ismember(app.marksList(:,1), channDelNames);
                    marksToDel = app.marksList(marksTimeSel&marksChannSel,:);

                    if ~isempty(app.multiEventDeletionHdl) && isvalid(app.multiEventDeletionHdl)
                        app.multiEventDeletionHdl.delete;
                    end
                    app.multiEventDeletionHdl = multiEventDeletion(app, marksToDel);

                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                else
                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                    app.plotSignalsBig();
                    app.plotSignalsSmall();
                    app.drawSlowObjects()
                end

                app.plotSignalsBig();
                app.plotSignalsSmall();
                app.drawSlowObjects();
            end
        end

        function handleGroupDeleteSmall(app)
            % close event editor if open
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end
            if ~isempty(app.multiEventDeletionHdl) && isvalid(app.multiEventDeletionHdl)
                app.multiEventDeletionHdl.delete;
            end

            if app.markingEnabled && app.markingMode
                app.markModeTxtSmall.Text = {'Multidelete'; ''};

                roi = drawrectangle(app.bigWdw);

                %% Determine in which panel the annotation is being made
                mousePos = get(0, 'PointerLocation');
                mouseSmallWdw = mousePos(1)>=app.smallWdw.Position(1) & mousePos(1)<=app.smallWdw.Position(1)+app.smallWdw.Position(3);

                roiPosition = roi.Position; 
                delete(roi);
                                
                % Find which channel was marked
                channDelSel= app.selChAvgY >= roiPosition(2) & app.selChAvgY <= roiPosition(2)+roiPosition(4);
                channDelNames = app.smallMtgLabels(channDelSel);

                % get Mark info
                startTime = roiPosition(1);
                endTime = roiPosition(1) + roiPosition(3);
                durationMs = endTime-startTime;

                if mouseSmallWdw && (durationMs > 0.001)

                    marksTimeSel = cell2mat(app.marksList(:,3))>=startTime & cell2mat(app.marksList(:,3))<=endTime;
                    marksChannSel = ismember(app.marksList(:,1), channDelNames);
                    marksToDel = app.marksList(marksTimeSel&marksChannSel,:);

                    if ~isempty(app.multiEventDeletionHdl) && isvalid(app.multiEventDeletionHdl)
                        app.multiEventDeletionHdl.delete;
                    end
                    app.multiEventDeletionHdl = multiEventDeletion(app, marksToDel);

                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                else
                    app.markModeTxtSmall.Text = {'Marking'; 'On'};
                    app.awaitMarkSpecKey = true;
                    app.markingMode = false;
                    app.plotSignalsBig();
                    app.plotSignalsSmall();
                    app.drawSlowObjects()
                end

                app.plotSignalsBig();
                app.plotSignalsSmall();
                app.drawSlowObjects();
            end
        end

        function markEditClickBig_Callback(app, source, eventdata, mark, markIdx)
            app.eventEditorHdl = eventEditor(app, app.mtgLabels, app.annotTypesAndColorsList(:,1), mark, markIdx, 1, app.fs);
        end

        function markEditClickSmall_Callback(app, source, eventdata, mark, markIdx)
            app.eventEditorHdl = eventEditor(app, app.smallMtgLabels, app.annotTypesAndColorsList(:,1), mark, markIdx, 2, app.fs);
        end

        function saveAnnotationsFile(app)
            if app.nrAnnotsEdits >= 20
                app.SavetofileMenuSelected();
                app.nrAnnotsEdits = 0;
            end
            app.autosaveTxt.Text = num2str(20-app.nrAnnotsEdits);
        end

        function closeAllSubApps(app)
            %% If ann external app is called but the 
            % user goes back to annotator then close external app
            if ~isempty(app.channSelectorAppHdl) && isvalid(app.channSelectorAppHdl)
                app.channSelectorAppHdl.delete;
            end
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end
            if ~isempty(app.annotationTypeSelectorHdl) && isvalid(app.annotationTypeSelectorHdl)
                app.annotationTypeSelectorHdl.delete;
            end
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end
            if ~isempty(app.badSegmentsEditorHdl) && isvalid(app.badSegmentsEditorHdl)
                app.badSegmentsEditorHdl.delete;
            end
            if ~isempty(app.multiEventDeletionHdl) && isvalid(app.multiEventDeletionHdl)
                app.multiEventDeletionHdl.delete;
            end

        end
        
    end
    
    methods (Access = public)
        %% Save new mark to file
        function saveMark(app, mark, panelNr)
            newMark = mark;
            username = app.settings.profileName{1};
            if isempty(username)
                username = 'n/a';
            end

            newMarkEntree = {newMark.channel, newMark.type, double(newMark.startTime), double(newMark.endTime), int64(newMark.startSample), int64(newMark.endSample), newMark.comments, newMark.channSpec, newMark.creationTime, username};
            if newMark.idx > size(app.marksList,1)
                app.marksList = cat(1, app.marksList, newMarkEntree);
            else
                app.marksList(newMark.idx, :) = newMarkEntree;
            end

            if sum(ismember(app.validAnnotTypes, newMark.type))==0 && not(strcmp(newMark.type, 'Bad'))
                app.validAnnotTypes = cat(1, app.validAnnotTypes, newMark.type);
            end

            if panelNr == 1
                app.lastMarkBig = newMark.type;
            else
                app.lastMarkSmall = newMark.type;
            end

            % Update annotations table if open
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.updateAnnotationsTable(app.marksList);
            end
            
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
            if app.AutosaveCheckBox.Value
                app.nrAnnotsEdits = app.nrAnnotsEdits +1;
            end
            app.saveAnnotationsFile();
        end

        function deleteMark(app, mark)
            newMark = mark;
            if newMark.idx <= size(app.marksList,1)
                app.marksList(newMark.idx, :) = [];
            else
                error(" Attempting to delete mark index outside of existing list");
            end

            % Update annotations table if open
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.updateAnnotationsTable(app.marksList);
            end

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
            if app.AutosaveCheckBox.Value
                app.nrAnnotsEdits = app.nrAnnotsEdits +1;
            end
            app.saveAnnotationsFile();
        end

        function deleteMultiEvent(app, marksToDelete)

            marksToDeleteIdxs = zeros(size(marksToDelete, 1),1);
            for mi = 1:size(marksToDelete, 1)
                dataIdx = 1;
                channOk = ismember(app.marksList(:,dataIdx), marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                typeOk = ismember(app.marksList(:,dataIdx), marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                startTimeOk = cell2mat(app.marksList(:,dataIdx)) == cell2mat(marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                endTimeOk = cell2mat(app.marksList(:,dataIdx)) == cell2mat(marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                startSampleOk = cell2mat(app.marksList(:,dataIdx)) == cell2mat(marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                endSampleOk = cell2mat(app.marksList(:,dataIdx)) == cell2mat(marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                commentsOk = 1; dataIdx = dataIdx+1; %ismember(app.marksList(:,dataIdx), marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                channSpecOk = cell2mat(app.marksList(:,dataIdx))== cell2mat(marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                creationTimeOk = ismember(app.marksList(:,dataIdx), marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;
                usernameOk = ismember(app.marksList(:,dataIdx), marksToDelete(mi, dataIdx)); dataIdx = dataIdx+1;

                matchSel = (channOk&typeOk&startTimeOk&endTimeOk&startSampleOk&endSampleOk&commentsOk&channSpecOk&creationTimeOk&usernameOk);
                if sum(matchSel) > 1
                    error('More than one event found that matches deletion selection');
                end
                marksToDeleteIdxs(mi) = find(matchSel);
            end

            if sum(marksToDeleteIdxs == 0)
                error('One fo the events selected for deletion was not found');
            else
                app.marksList(marksToDeleteIdxs,:) = [];
            end

            % Update annotations table if open
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.updateAnnotationsTable(app.marksList);
            end

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
            if app.AutosaveCheckBox.Value
                app.nrAnnotsEdits = app.nrAnnotsEdits +1;
            end
            app.saveAnnotationsFile();
        end

        function updateChannels(app, selectedChannelLabels)
            newMtgLabels = selectedChannelLabels;

            app.selMtgsToShowIdxs = ismember(app.mtgLabelsAbs, newMtgLabels);
            app.mtgLabels = app.mtgLabelsAbs(app.selMtgsToShowIdxs);
            app.mtgSignals = app.mtgSignalsAbs(app.selMtgsToShowIdxs,:);
            app.mtgSignalsLow = app.mtgSignalsLowAbs(app.selMtgsToShowIdxs,:);
            app.mtgSignalsHFO = app.mtgSignalsHFOAbs(app.selMtgsToShowIdxs,:); 
            app.mtgSignalsRipple = app.mtgSignalsRippleAbs(app.selMtgsToShowIdxs,:);
            app.mtgSignalsFR = app.mtgSignalsFRAbs(app.selMtgsToShowIdxs,:);
            app.nrMtgs = size(app.mtgLabels, 1);

            app.selBigSgnlsIdx = false(app.nrMtgs,1);
            app.settings.lastSelBigMtgs = app.selBigSgnlsIdx;
            app.bigSgnlColors = repmat([0 0 0], app.nrMtgs, 1);

            app.selSmallSgnlsIdx = 0;
            app.smallSgnlColors = [];
            app.spctgrmSigIdx = 0;

            cla(app.bigWdw);
            cla(app.smallWdw);
            cla(app.heatmap);
            app.selSmallSgnlsIdx = 0;
            app.smallSgnlColors = [];
            app.smallMtgLabels = [];

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        function saveAnnotationSettings(app, eventsAndColorList)
            app.annotTypesAndColorsList = eventsAndColorList;
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end

            % Save annotation types to profile
            profilesFilename = 'profiles.mat';
            profileExistsTest = isfile(profilesFilename) &&  isfield(app.settings, 'profileName') && ~isempty(app.settings.profileName);
            if profileExistsTest
                load(profilesFilename, 'savedProfiles');
                existingUserProfiles = savedProfiles;
                prflIdx = find(ismember(existingUserProfiles(:,1), app.settings.profileName), 1);
                profile = existingUserProfiles(prflIdx,2);
                profile = profile{1};
                profile.annotTypesAndColorsList = app.annotTypesAndColorsList;
                existingUserProfiles{prflIdx,2} = profile;
                savedProfiles = existingUserProfiles;
                save(profilesFilename, 'savedProfiles');
            end
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
        end

        function updateValidAnnotTypes(app, selAnnotTypes)
            app.validAnnotTypes = selAnnotTypes;
            app.validAnnotTypes(ismember(app.validAnnotTypes, 'Bad')) = [];
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        function saveBadSectors(app, badsegAnnotations)
            nrBadSegmemts = size(badsegAnnotations,1);
            app.badsegAnnotations = {};
            for bsi = 1:nrBadSegmemts
                %% Update mark values
                channSpecVal = true();
                if strcmp(badsegAnnotations{bsi,1}, 'All')
                    channSpecVal = false();
                end
                badmark.channel = badsegAnnotations{bsi,1};
                badmark.type = 'Bad';
                badmark.startTime = badsegAnnotations{bsi,2};
                badmark.endTime = badsegAnnotations{bsi,3};
                badmark.startSample = int64(badmark.startTime*app.fs);
                badmark.endSample = int64(badmark.endTime*app.fs);
                badmark.comments = convertCharsToStrings("Bad");
                badmark.channSpec = channSpecVal;
                t = datetime;
                timeStr = strcat(num2str(t.Day), '.', num2str(t.Month), '.',num2str(t.Year));
                timeStr = strcat(timeStr, '__',num2str(t.Hour), ':',num2str(t.Minute), ':',num2str(round(t.Second)));
                badmark.creationTime = timeStr;
                username = app.settings.profileName{1};
                if isempty(username)
                    username = 'n/a';
                end

                newBadmarkEntree = {badmark.channel, badmark.type, double(badmark.startTime), double(badmark.endTime), int64(badmark.startSample), int64(badmark.endSample), badmark.comments, badmark.channSpec, badmark.creationTime, username};
                app.badsegAnnotations = cat(1, app.badsegAnnotations, newBadmarkEntree);                
            end
            if not(isempty(app.marksList))
                badSegsSel = ismember(app.marksList(:,2), 'Bad');
                app.marksList(badSegsSel,:) = [];
            end
            app.badsegAnnotations = removeRepeatedMarks(app.badsegAnnotations);
            app.marksList = cat(1, app.marksList, app.badsegAnnotations);        
            % Update annotations table if open
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.updateAnnotationsTable(app.marksList);
            end
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
        end

        function goToAnnotation(app, goToTime)
            newLoc = goToTime * app.fs;
            if newLoc > app.nSamples
                newLoc = app.nSamples;
            end
            if newLoc < 1
                newLoc = 1;
            end
            app.bigWdwLoc = int64(newLoc);
            app.smallWdwLoc = int64(newLoc);

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        function clearAnnotationTableHdl(app)
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.delete;
                app.annotsTableHdl = [];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('startupFcn');
            if not(app.standalone)
                [thisFilePath,~,~] = fileparts(mfilename('fullpath'));
                cd(thisFilePath);
                run('startupScript.m');
    
                desktop = com.mathworks.mde.desk.MLDesktop.getInstance();
                mf = desktop.getMainFrame();
                mf.setMinimized(true);
            end

            app.initializeVariables();
            
            app.permits = checkUsagePermission();

            if isempty(app.permits) || not(app.permits.generalUse)
                disp('Permission not granted');
                pause(3);
                app.delete();
            else
                settingsFN = 'settings.mat';
                if isfile(settingsFN)
                    storedSettings = load(settingsFN);
                    storedSettings = storedSettings.settings;
       
                    app.settings = storedSettings;
    
                    normalizePositionsGUI(app);
    
                    if isfile(app.settings.eegFilepath)
                        [filepath, app.patName, ext] = fileparts(app.settings.eegFilepath);
                        %read eeg file header;
                        disp(strcat("Open File: ", app.settings.eegFilepath));
                        hdr = ft_read_header(app.settings.eegFilepath);
                        app.fs = hdr.Fs;
            
                        spaceStr = "                        ";
                        spaceStr = strcat(spaceStr, spaceStr, spaceStr);
                        app.annotateHFO_GUI.Name = strcat("elpi", spaceStr,...
                            app.patName, spaceStr,...
                            "Sampling Rate: ", num2str(app.fs), " Hz");
        
                        app.edfAnnotsFilepath = strcat(filepath, "\", app.patName, "_.mat");

                        app.annotTypesAndColorsList = app.settings.annotTypesAndColorsList;
            
                        app.readMontageSignals();
            
                        app.plotSignalsBig();
                        app.plotSignalsSmall();
                        app.drawSlowObjects()
            
                        if strcmp(getenv('COMPUTERNAME'), 'LAPTOP-5D668HJL')
                            app.smallWdw.Position(1) = app.heatmap.Position(1)+6;
                            app.smallWdw.Position(3) = app.heatmap.Position(3)-17;
                        end
                    else
                        disp('EEG not found, open initAnnotateHFO');
                        initAnnotateHFO();
                        app.delete; % Shut down the entire app (will reopen in settings app)
                    end
                else
                    disp('No Settings found, open initAnnotateHFO');
                    initAnnotateHFO();
                    app.delete; % Shut down the entire app (will reopen in settings app)
                end 
            end

        end

        % Window key press function: annotateHFO_GUI
        function annotateHFO_GUIWindowKeyPress(app, event)
            key = event.Key;

            %% Determine which panel to modify
            mousePos = get(0, 'PointerLocation');
            mouseBigWdw = mousePos(1)>=app.bigWdw.Position(1) & mousePos(1)<=app.bigWdw.Position(1)+app.bigWdw.Position(3);
            mouseSmallWdw = mousePos(1)>=app.smallWdw.Position(1) & mousePos(1)<=app.smallWdw.Position(1)+app.smallWdw.Position(3);
            mouseHeatmap = false();
            % mouseHeatmap= mousePos(1)>=app.heatmap.Position(1) & mousePos(1)<=app.heatmap.Position(1)+app.heatmap.Position(3)&...
            %     mousePos(2)>=app.heatmap.Position(2) & mousePos(2)<=app.heatmap.Position(2)+app.heatmap.Position(4);

            %if ~isempty(event.Source.CurrentObject)
                if mouseBigWdw %isequal(event.Source.CurrentObject.Tag,'allCh')
                    %% Control left panel
                    winLen = round(app.bigWdwLenS* app.fs);
                    yInterv = app.yIntervBig;
                    decPl = power(10, floor(log10(yInterv)))/10;
                    if isequal(key, 'uparrow')
                        yInterv = yInterv - decPl;
                        if yInterv > 0
                            app.yIntervBig = yInterv;
                        end
                        app.plotSignalsBig();
                    elseif isequal(key, 'downarrow')
                        yInterv = yInterv + decPl;
                        app.yIntervBig = yInterv;
                        app.plotSignalsBig();
                    elseif isequal(key, 'rightarrow')
                        if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                            app.bigWdwLoc = int64(app.bigWdwLoc + ceil(winLen/10));
                        else
                            app.bigWdwLoc = int64(app.bigWdwLoc + winLen);
                        end
                        app.plotSignalsBig();
                    elseif isequal(key, 'leftarrow')
                        if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                            app.bigWdwLoc = int64(app.bigWdwLoc - ceil(winLen/10));
                        else
                            app.bigWdwLoc = int64(app.bigWdwLoc - winLen);
                        end
                        if app.bigWdwLoc < 1
                            app.bigWdwLoc = int64(1);
                        end
                        app.plotSignalsBig();
                    end
                elseif mouseSmallWdw %isequal(event.Source.CurrentObject.Tag,'selChs')
                    %% Control right panel
                    winLen = round(app.smallWdwLenS* app.fs);
                    yInterv = app.yIntervSmall;
                    decPl = power(10, floor(log10(yInterv)))/10;
                    if app.isAdjustAction(event)
                        if isequal(key, 'uparrow')
                            yInterv = yInterv - decPl;
                            if yInterv > 0
                                app.yIntervSmall = yInterv;
                                app.plotSignalsSmall();
                            end
                        elseif isequal(key, 'downarrow')
                            yInterv = yInterv + decPl;
                            app.yIntervSmall = yInterv;
                            app.plotSignalsSmall();
                        elseif isequal(key, 'rightarrow')
                            if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                                app.smallWdwLoc = int64(app.smallWdwLoc + ceil(winLen/10));
                            else
                                app.smallWdwLoc = int64(app.smallWdwLoc + winLen);
                            end
                            app.plotSignalsSmall();
                        elseif isequal(key, 'leftarrow')
                            if ~isempty(event.Modifier) && strcmp(event.Modifier{1},'shift')
                                app.smallWdwLoc = int64(app.smallWdwLoc - ceil(winLen/10));
                            else
                                app.smallWdwLoc = int64(app.smallWdwLoc - winLen);
                            end
                            if app.smallWdwLoc < 1
                                app.smallWdwLoc = int64(1);
                            end
                            app.plotSignalsSmall();
                        end
                    end
                elseif mouseHeatmap %isequal(event.Source.CurrentObject.Tag,'heatmap')
                    %% Control heatmap panel
                    if not(isempty(app.zLimsSpctgrm))
                        if isequal(key, 'uparrow')
                            app.zLimsSpctgrm(2) = app.zLimsSpctgrm(2) + app.zLimsSpctgrm(2)*0.2;
                        elseif isequal(key, 'downarrow')
                            app.zLimsSpctgrm(2) = app.zLimsSpctgrm(2) - app.zLimsSpctgrm(2)*0.2;
                        end
                        app.plotHeatmap();
                    end
                end
            
            %end
            annotKeyPress =  isequal(key, 's') ||  isequal(key, 'a');
            multiDeleteKeyPress = isequal(key, 'd');
            if app.awaitMarkSpecKey && annotKeyPress && app.markingEnabled && (mouseSmallWdw || mouseBigWdw)
                app.chSpecMark = isequal(key, 's');
                if mouseBigWdw
                    app.markingMode = true;
                    app.awaitMarkSpecKey = false;
                    app.handleNewMarkEventBig();
                elseif mouseSmallWdw
                    app.markingMode = true;
                    app.awaitMarkSpecKey = false;
                    app.handleNewMarkEventSmall();
                end
            elseif app.awaitMarkSpecKey && multiDeleteKeyPress && app.markingEnabled && (mouseSmallWdw || mouseBigWdw)
                if mouseBigWdw
                    app.markingMode = true;
                    app.awaitMarkSpecKey = false;
                    app.handleGroupDeleteBig();
                elseif mouseSmallWdw
                    app.markingMode = true;
                    app.awaitMarkSpecKey = false;
                    app.handleGroupDeleteSmall();
                end                
            end

        end

        % Window scroll wheel function: annotateHFO_GUI
        function annotateHFO_GUIWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            if verticalScrollAmount ~= 3
                stop = 1;
            end
            scrollCtr = event.VerticalScrollCount;

            %% Determine which panel to modify
            mousePos = get(0, 'PointerLocation');
            mouseBigWdw = mousePos(1)>=app.bigWdw.Position(1) & mousePos(1)<=app.bigWdw.Position(1)+app.bigWdw.Position(3);
            mouseSmallWdw = mousePos(1)>=app.smallWdw.Position(1) & mousePos(1)<=app.smallWdw.Position(1)+app.smallWdw.Position(3);
            mouseHeatmap = false();
            % mouseHeatmap= mousePos(1)>=app.heatmap.Position(1) & mousePos(1)<=app.heatmap.Position(1)+app.heatmap.Position(3)&...
            %     mousePos(2)>=app.heatmap.Position(2) & mousePos(2)<=app.heatmap.Position(2)+app.heatmap.Position(4);

            newLoc = app.bigWdwLoc;
            if mouseBigWdw
                newLoc = app.bigWdwLoc + scrollCtr*(app.fs*app.bigWdwLenS/10);
            elseif mouseSmallWdw
                newLoc = app.smallWdwLoc + scrollCtr*(app.fs*app.smallWdwLenS/10);
            end
            
            if newLoc > app.nSamples
                newLoc = app.nSamples;
            end
            if newLoc < 1
                newLoc = 1;
            end

            app.bigWdwLoc = int64(newLoc);
            app.smallWdwLoc = int64(newLoc);
            
            app.updateSlideBars();
            app.plotSignalsBig();
            app.plotSignalsSmall();
            
            %% Can't darwSlowObjects because the scrolling because too slow
            % start a timer instead to drawSmallObjects after last scroll
            % action
            delete(app.timer);
            app.timer = timer;  
            app.timer.StartDelay = 0.1;
            app.timer.TimerFcn = @(~,~) app.drawSlowObjects();
            start(app.timer)
        end

        % Key release function: annotateHFO_GUI
        function annotateHFO_GUIKeyRelease(app, event)
            key = event.Key;
            app.handleKeyRelease(event);
        end

        % Window key release function: annotateHFO_GUI
        function annotateHFO_GUIWindowKeyRelease(app, event)
            key = event.Key;
            app.handleKeyRelease(event);         
        end

        % Window button down function: annotateHFO_GUI
        function annotateHFO_GUIWindowButtonDown(app, event)
            currObjT = event.Source.CurrentObject;
            markLampOK = isa(currObjT,'matlab.ui.control.Lamp') && strcmp(currObjT.Tag, 'MarkLamp');

            if markLampOK && app.permits.newAnnots
                if ~app.markingEnabled
                    app.markingEnabled = true();
                    app.markingMode = 0;
                else
                    app.markingEnabled = false();
                    app.markingMode = 0;
                end
                app.updateMarkingStateLamp();
            end

            app.closeAllSubApps()
        end

        % Callback function: not associated with a component
        function bigWdwFltrBttnButtonPushed(app, event)
            app.fltrBttnBigIdx = app.fltrBttnBigIdx + 1;
            if app.fltrBttnBigIdx > 5
                app.fltrBttnBigIdx = 1;
            end

            if app.fltrBttnBigIdx == 1
                app.bigWdwFltrBttn.Text = 'Raw';
            elseif app.fltrBttnBigIdx == 2
                fltrStr = strcat(num2str(app.settings.filterValues(1,1)), '-', num2str(app.settings.filterValues(1,2)));
                app.bigWdwFltrBttn.Text = fltrStr;
            elseif app.fltrBttnBigIdx == 3
                fltrStr = strcat(num2str(app.settings.filterValues(2,1)), '-', num2str(app.settings.filterValues(2,2)));
                app.bigWdwFltrBttn.Text = fltrStr;
            elseif app.fltrBttnBigIdx == 4
                fltrStr = strcat(num2str(app.settings.filterValues(3,1)), '-', num2str(app.settings.filterValues(3,2)));
                app.bigWdwFltrBttn.Text = fltrStr;
            elseif app.fltrBttnBigIdx == 5
                fltrStr = strcat(num2str(app.settings.filterValues(4,1)), '-', num2str(app.settings.filterValues(4,2)));
                app.bigWdwFltrBttn.Text = fltrStr;
            end
            %%slow event
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
        end

        % Button pushed function: smallWdwFltrBttn
        function smallWdwFltrBttnButtonPushed(app, event)
            app.fltrBttnSmallIdx = app.fltrBttnSmallIdx + 1;
            if app.fltrBttnSmallIdx > 5
                app.fltrBttnSmallIdx = 1;
            end
            if app.fltrBttnSmallIdx == 1
                app.smallWdwFltrBttn.Text = 'Raw';
            elseif app.fltrBttnSmallIdx == 2
                app.smallWdwFltrBttn.Text = '1-40 Hz';
            elseif app.fltrBttnSmallIdx == 3
                app.smallWdwFltrBttn.Text = '80-500 Hz';
            elseif app.fltrBttnSmallIdx == 4
                app.smallWdwFltrBttn.Text = '80-250 Hz';
            elseif app.fltrBttnSmallIdx == 5
                app.smallWdwFltrBttn.Text = '250-500 Hz';
            end
            %% slow event
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects();
            set(app.annotateHFO_GUI, 'CurrentAxes', app.bigWdw);
        end

        % Button pushed function: heatmapFltrBttn
        function heatmapFltrBttnPushed(app, event)
            app.fltrBttnSpctgrmIdx = app.fltrBttnSpctgrmIdx + 1;
            if app.fltrBttnSpctgrmIdx > 5
                app.fltrBttnSpctgrmIdx = 1;
            end
            if app.fltrBttnSpctgrmIdx == 2
                app.fltrBttnSpctgrmIdx = 3;
            end
            
            if app.fltrBttnSpctgrmIdx == 1
                app.heatmapFltrBttn.Text = 'Raw';
            elseif app.fltrBttnSpctgrmIdx == 3
                app.heatmapFltrBttn.Text = '80-500 Hz';
            elseif app.fltrBttnSpctgrmIdx == 4
                app.heatmapFltrBttn.Text = '80-250 Hz';
            elseif app.fltrBttnSpctgrmIdx == 5
                app.heatmapFltrBttn.Text = '250-500 Hz';
            end
            app.plotHeatmap();
        end

        % Button pushed function: refreshHeatmap
        function refreshHeatmapPushed(app, event)
            app.zLimsSpctgrm = [];
            app.plotHeatmap();
        end

        % Value changed function: cwtLockCheck
        function cwtLockCheckValueChanged(app, event)
            app.spctgrmScroolLock = app.cwtLockCheck.Value;
        end

        % Callback function: not associated with a component
        function bigWdwSetLenValueChanged(app, event)
            value = app.bigWdwSetLen.Value;
            if value > 0 && value < app.nSamples/app.fs
                app.bigWdwLenS = value;
                %%slow event
                app.plotSignalsBig();
                app.plotSignalsSmall();
                app.drawSlowObjects()
            end
            
        end

        % Value changed function: smallWdwSetLen
        function smallWdwSetLenValueChanged(app, event)
            value = app.smallWdwSetLen.Value;
            if value > 0 && value < app.nSamples/app.fs
                app.smallWdwLenS = value;
                %% slow event
                app.plotSignalsSmall();
                app.drawMarksSmall();
                if app.spctgrmScroolLock
                    app.zLimsSpctgrm = [];
                    app.plotHeatmap();
                end
            end
            
        end

        % Callback function: not associated with a component
        function bigWdwButtonDown(app, event)

            %% Handle selected channel label from big screen
            % Select channels by clicking on channel label
            % Only accepts clicks lower than min(ylim)
            if event.IntersectionPoint(1) > min(app.bigWdw.XLim)
                return
            end
            
            % Get closest y-tick to the click
            [~, tickIdx] = min(abs(app.bigWdw.YTick - event.IntersectionPoint(2)));
            selYLabelIdx = length(app.bigWdw.YTick) - (tickIdx-1);
            %selYLabelIdx = source.SeriesIndex
            signalSelButton = event.Button==1;
            if signalSelButton
                app.selectedSiganlHdlr_Big(selYLabelIdx);
            end
        end

        % Button down function: smallWdw
        function smallWdwButtonDown(app, event)
            % Select channels by clicking on channel label
            % Only accept left mouse clicks
            if event.Button ~= 1
                return
            end
            % Only accepts clicks lower than min(ylim)
            if event.IntersectionPoint(1) > min(app.smallWdw.XLim)
                return
            end
            % Get closest y-tick to the click
            [~, tickIdx] = min(abs(app.smallWdw.YTick - event.IntersectionPoint(2)));
            tickIdx = length(app.smallWdw.YTick) - (tickIdx-1);
            app.highlightClickChLabelSmall(tickIdx);
        end

        % Callback function: not associated with a component
        function LoadfromfileMenuSelected(app, event)
            [app.annotsPath, patName,~] = fileparts(app.settings.eegFilepath);
            [annotsFilename, app.annotsPath] = uigetfile({'*.mat'}, 'Select annotations file', app.annotsPath);
            if annotsFilename ~= 0
                annotsFilename = strcat(app.annotsPath, annotsFilename);
                data = load(annotsFilename);
                if isfield(data,'detections')
                    detections = data.detections;
                    if not(isempty(detections))
                        if size(detections, 2) < 8
                            % Compatibility, add channSpecific flag
                            emptyCells = repmat({true}, size(detections,1),1);
                            detections = cat(2, detections, emptyCells);
                        end
                        if size(detections, 2) < 9
                            % Compatibility, added creationTime string
                            emptyCells = repmat({"n/a"}, size(detections,1),1);
                            detections = cat(2, detections, emptyCells);
                        end
                        if size(detections, 2) < 10
                            % Compatibility, added Username string
                            emptyCells = repmat({"n/a"}, size(detections,1),1);
                            detections = cat(2, detections, emptyCells);
                        end
                        
                        % Cast to correct possible differences in type
                        detections(:,3) = cellfun(@double, detections(:,3),'UniformOutput',false);  % startTime
                        detections(:,4) = cellfun(@double, detections(:,4),'UniformOutput',false);  % endTime
                        detections(:,5) = cellfun(@int64, detections(:,5),'UniformOutput',false);  % startSample
                        detections(:,6) = cellfun(@int64, detections(:,6),'UniformOutput',false);  % endSample
        
                        if isempty(app.marksList)
                            app.marksList = detections;
                        else
                            checkCorrectDetectionsType(detections, app.marksList);
                            app.marksList = cat(1, app.marksList, detections);
                        end
        
                        if not(isempty(app.marksList))
                            app.marksList = removeRepeatedMarks(app.marksList);
                        end

                        % Update annotations table if open
                        if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                            app.annotsTableHdl.updateAnnotationsTable(app.marksList);
                        end
                    end
                end

                annotTypes = unique(app.marksList(:,2));
                app.updateValidAnnotTypes(annotTypes);
                app.plotSignalsBig();
                app.plotSignalsSmall();
                app.drawSlowObjects()

            end
        end

        % Callback function: not associated with a component
        function OpenMenuSelected(app, event)
            app.initializeVariables();
            %system('initAnnotateHFO.exe &');
            initAnnotateHFO();
            app.delete; % Shut down the entire app (will reopen in settings app)
        end

        % Callback function: not associated with a component
        function ChannelsMenuSelected(app, event)
            if ~isempty(app.channSelectorAppHdl) 
                if isvalid(app.channSelectorAppHdl)
                    app.channSelectorAppHdl.delete;
                end
            end
            app.channSelectorAppHdl = channelSelectorGUI(app, app.mtgLabelsAbs, app.mtgLabels);
        end

        % Callback function: not associated with a component
        function SavetofileMenuSelected(app, event)
            t = datetime;
            timeStr = strcat(num2str(t.Day), '_', num2str(t.Month), '_',num2str(t.Year));
            timeStr = strcat(timeStr, '__',num2str(t.Hour), '_',num2str(t.Minute), '_',num2str(round(t.Second)));

            [tempAnnotsPath, patName,~] = fileparts(app.settings.eegFilepath);
            if isempty(app.annotsPath)
                app.annotsPath = tempAnnotsPath;
            end
            annotsFilename = strcat(patName, '_', timeStr, '_annotations.mat');
            annotsFilename = strcat(app.annotsPath, '\', annotsFilename);

            [annotsFilename, app.annotsPath, indx] = uiputfile(annotsFilename);
            errorStr = '';
            if annotsFilename ~= 0
                app.editedAnnotsFN = strcat(app.annotsPath, annotsFilename);
                detections = app.marksList;
                save(app.editedAnnotsFN, 'detections');
                chechSavedData = load(app.editedAnnotsFN, 'detections');
                errorStr = checkSavedDetections(detections, chechSavedData.detections);
            else
                errorStr = 'Annotation Save Error: invalid filename or path';
            end
            if not(isempty(errorStr))
                app.WarningTxt_Label.Text = {errorStr};
                app.WarningTxt_Label.Visible = 'on';
                pause(3);
                app.WarningTxt_Label.Visible = 'off';
            end
        end

        % Callback function: not associated with a component
        function selectTypeToMarkMenuSelected(app, event)
            if app.permits.annotsMenu
                app.annotsSetupHdl = annotationsSetup(app, app.annotTypesAndColorsList);
            end
        end

        % Callback function: not associated with a component
        function selectTypeToShowMenuSelected(app, event)
            annotTypes = unique(app.marksList(:,2));
            annotTypes(ismember(annotTypes, 'Bad')) = [];
            if ~isempty(app.annotationTypeSelectorHdl) && isvalid(app.annotationTypeSelectorHdl)
                app.annotationTypeSelectorHdl.delete;
            end
            app.annotationTypeSelectorHdl = annotationTypeSelector(app, annotTypes);
        end

        % Callback function: not associated with a component
        function EditBadSegmentsMenuSelected(app, event)
            app.badSegmentsEditorHdl = badSegmentsEditor(app, app.fs, app.nSamples, app.mtgLabelsAbs, app.badsegAnnotations);
        end

        % Callback function: not associated with a component
        function bigWdwSliderValueChanged(app, event)
            value = app.bigWdwSlider.Value/100;
            app.bigWdwLoc = int64(app.nSamples * value);
            if app.bigWdwLoc == 0
                app.bigWdwLoc = int64(1);
            end
            app.smallWdwLoc = app.bigWdwLoc;
            sliderVal = double(100*app.bigWdwLoc/app.nSamples);
            app.bigWdwSlider.Value = sliderVal;
            app.smallWdwSlider.Value = sliderVal;

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        % Value changed function: smallWdwSlider
        function smallWdwSliderValueChanged(app, event)
            value = app.smallWdwSlider.Value/100;
            app.smallWdwLoc = int64(app.nSamples * value);
            if app.smallWdwLoc == 0
                app.smallWdwLoc = int64(1);
            end
            app.bigWdwLoc = app.smallWdwLoc;
            sliderVal = double(100*app.smallWdwLoc/app.nSamples);
            app.bigWdwSlider.Value = sliderVal;
            app.smallWdwSlider.Value = sliderVal;
            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        % Callback function: not associated with a component
        function ClearallMenuSelected(app, event)
            app.marksList = [];
            app.badsegAnnotations = [];
            app.validAnnotTypes = [];
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.delete;
                app.annotsTableHdl = [];
            end

            app.plotSignalsBig();
            app.plotSignalsSmall();
            app.drawSlowObjects()
        end

        % Callback function: not associated with a component
        function showAnnotationsListMenuSelected(app, event)
            app.annotsTableHdl = annotationsTable(app, app.marksList);
        end

        % Close request function: annotateHFO_GUI
        function annotateHFO_GUICloseRequest(app, event)
            if ~isempty(app.channSelectorAppHdl) && isvalid(app.channSelectorAppHdl)
                app.channSelectorAppHdl.delete;
            end
            if ~isempty(app.eventEditorHdl) && isvalid(app.eventEditorHdl)
                app.eventEditorHdl.delete;
            end
            if ~isempty(app.annotationTypeSelectorHdl) && isvalid(app.annotationTypeSelectorHdl)
                app.annotationTypeSelectorHdl.delete;
            end
            if ~isempty(app.annotsSetupHdl) && isvalid(app.annotsSetupHdl)
                app.annotsSetupHdl.delete;
            end
            if ~isempty(app.badSegmentsEditorHdl) && isvalid(app.badSegmentsEditorHdl)
                app.badSegmentsEditorHdl.delete;
            end
            if ~isempty(app.occRateReportHdl) && isvalid(app.occRateReportHdl)
                app.occRateReportHdl.delete;
            end
            if ~isempty(app.occRateReportHdl) && isvalid(app.occRateReportHdl)
                app.occRateReportHdl.delete;
            end
            if ~isempty(app.annotsTableHdl) && isvalid(app.annotsTableHdl)
                app.annotsTableHdl.delete;
            end

            %% Save settings
            app.settings.lastSelBigMtgs = app.selBigSgnlsIdx;
            app.settings.yIntervBig = app.yIntervBig;
            app.settings.yIntervSmall = app.yIntervSmall;
            app.settings.fltrBttnBigIdx = app.fltrBttnBigIdx;
            app.settings.fltrBttnSmallIdx = app.fltrBttnSmallIdx;
            app.settings.eegSampleLocation = app.bigWdwLoc;

            settings = app.settings;
            settingsFN = 'settings.mat';
            save(settingsFN, 'settings')

            app.delete();
            
            computerName = getenv('COMPUTERNAME');
            devComputers = {'LAPTOP-TFQFNF6U'; 'DLP'};
            if sum(ismember(devComputers, computerName)) == 0
                quit();
            end
        end

        % Callback function: not associated with a component
        function partRewButtonPushed(app, event)
            % if app.filepartSel > 1
            %     app.filepartSel = app.filepartSel - 1;
            %     app.readMontageSignals();
            %     app.plotSignalsBig();
            %     app.plotSignalsSmall();
            %     app.drawSlowObjects();
            % end
        end

        % Callback function: not associated with a component
        function partFwdButtonPushed(app, event)
            % if app.filepartSel < app.totalNrFileparts
            %     app.filepartSel = app.filepartSel + 1;
            %     app.readMontageSignals();
            %     app.plotSignalsBig();
            %     app.plotSignalsSmall();
            %     app.drawSlowObjects();
            % end
        end

        % Callback function: not associated with a component
        function OccRateMenuSelected(app, event)
            if ~isempty(app.occRateReportHdl) && isvalid(app.occRateReportHdl)
                app.occRateReportHdl.delete;
            end
            durationM = size(app.mtgSignals, 2)/app.fs/60;
            app.occRateReportHdl = occRateReport(app.mtgLabelsAbs, app.marksList, durationM);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.annotateHFO_GUI = uifigure('Visible', 'off');
            app.annotateHFO_GUI.Name = 'elpi';
            app.annotateHFO_GUI.Icon = fullfile(pathToMLAPP, 'icons', 'HFO2.png');
            app.annotateHFO_GUI.CloseRequestFcn = createCallbackFcn(app, @annotateHFO_GUICloseRequest, true);
            app.annotateHFO_GUI.WindowButtonDownFcn = createCallbackFcn(app, @annotateHFO_GUIWindowButtonDown, true);
            app.annotateHFO_GUI.WindowScrollWheelFcn = createCallbackFcn(app, @annotateHFO_GUIWindowScrollWheel, true);
            app.annotateHFO_GUI.WindowKeyPressFcn = createCallbackFcn(app, @annotateHFO_GUIWindowKeyPress, true);
            app.annotateHFO_GUI.WindowKeyReleaseFcn = createCallbackFcn(app, @annotateHFO_GUIWindowKeyRelease, true);
            app.annotateHFO_GUI.KeyReleaseFcn = createCallbackFcn(app, @annotateHFO_GUIKeyRelease, true);
            app.heatmap = uiaxes(app.annotateHFO_GUI);
            xlabel(app.heatmap, 'Time (s)')
            zlabel(app.heatmap, 'Power')
            app.heatmap.Tag = 'heatmap';
            app.smallWdw = uiaxes(app.annotateHFO_GUI);
            app.smallWdw.XTick = [];
            app.smallWdw.XTickLabel = '';
            app.smallWdw.ButtonDownFcn = createCallbackFcn(app, @smallWdwButtonDown, true);
            app.smallWdw.Tag = 'selChs';
            app.smallWdwFltrBttn = uibutton(app.annotateHFO_GUI, 'push');
            app.smallWdwFltrBttn.ButtonPushedFcn = createCallbackFcn(app, @smallWdwFltrBttnButtonPushed, true);
            app.smallWdwFltrBttn.Tag = 'selChs';
            app.smallWdwFltrBttn.Text = 'Raw';
            app.heatmapFltrBttn = uibutton(app.annotateHFO_GUI, 'push');
            app.heatmapFltrBttn.ButtonPushedFcn = createCallbackFcn(app, @heatmapFltrBttnPushed, true);
            app.heatmapFltrBttn.Tag = 'heatmap';
            app.heatmapFltrBttn.Text = 'Raw';
            app.refreshHeatmap = uibutton(app.annotateHFO_GUI, 'push');
            app.refreshHeatmap.ButtonPushedFcn = createCallbackFcn(app, @refreshHeatmapPushed, true);
            app.refreshHeatmap.Tag = 'heatmap';
            app.refreshHeatmap.Icon = fullfile(pathToMLAPP, 'icons', 'Refresh.jpg');
            app.refreshHeatmap.Text = '';
            app.cwtLockCheck = uicheckbox(app.annotateHFO_GUI);
            app.cwtLockCheck.ValueChangedFcn = createCallbackFcn(app, @cwtLockCheckValueChanged, true);
            app.cwtLockCheck.Text = 'Lock';
            app.cwtLockCheck.Value = true;
            app.smallWdwSetLen = uieditfield(app.annotateHFO_GUI, 'numeric');
            app.smallWdwSetLen.ValueChangedFcn = createCallbackFcn(app, @smallWdwSetLenValueChanged, true);
            app.smallWdwSetLen.Value = 1;
            app.wdwsLengthLabel = uilabel(app.annotateHFO_GUI);
            app.wdwsLengthLabel.Text = 'Window lengths (s)';
            app.smallWdwSlider = uislider(app.annotateHFO_GUI);
            app.smallWdwSlider.MajorTicks = [];
            app.smallWdwSlider.ValueChangedFcn = createCallbackFcn(app, @smallWdwSliderValueChanged, true);
            app.smallWdwSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.annotateHFO_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smallPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.annotateHFO_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotateHFO_GUI)
        end
    end
end